classdef ProcessGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PMVAltpapierKlassifiziererUIFigure  matlab.ui.Figure
        OptionenMenu                   matlab.ui.container.Menu
        ProzessEinstellungenladenMenu  matlab.ui.container.Menu
        ProzessEinstellungenspeichernMenu  matlab.ui.container.Menu
        LoggerEinstellungenMenu        matlab.ui.container.Menu
        HilfeMenu_2                    matlab.ui.container.Menu
        HilfeMenu_3                    matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        GesamtprozessTab               matlab.ui.container.Tab
        IsolierungPanel                matlab.ui.container.Panel
        TrommelLampLabel               matlab.ui.control.Label
        DrumFeeder_Lamp                matlab.ui.control.Lamp
        ConvBelt_EditField             matlab.ui.control.EditField
        FrderbandLamp_3Label           matlab.ui.control.Label
        ConvBelt_Lamp                  matlab.ui.control.Lamp
        Robot_EditField                matlab.ui.control.EditField
        RoboterLampLabel               matlab.ui.control.Label
        Robot_Lamp                     matlab.ui.control.Lamp
        DrumFeeder_EditField           matlab.ui.control.EditField
        Gripper_EditField              matlab.ui.control.EditField
        GreiferLampLabel               matlab.ui.control.Label
        Gripper_Lamp                   matlab.ui.control.Lamp
        ObjectDetection_EditField      matlab.ui.control.EditField
        ObjekterkennungLamp_2Label     matlab.ui.control.Label
        ObjectDetection_Lamp           matlab.ui.control.Lamp
        MesssystemPanel                matlab.ui.container.Panel
        WeighingBelt_EditField         matlab.ui.control.EditField
        FrderbandLamp_4Label           matlab.ui.control.Label
        WeighingBelt_Lamp              matlab.ui.control.Lamp
        Camera_EditField               matlab.ui.control.EditField
        Scale_EditField                matlab.ui.control.EditField
        KameraLampLabel                matlab.ui.control.Label
        Camera_Lamp                    matlab.ui.control.Lamp
        WaageLampLabel                 matlab.ui.control.Label
        Scale_Lamp                     matlab.ui.control.Lamp
        Light_EditField                matlab.ui.control.EditField
        LichtLabel                     matlab.ui.control.Label
        Light_Lamp                     matlab.ui.control.Lamp
        MeasuringSystem_EditField      matlab.ui.control.EditField
        MesssystemLabel                matlab.ui.control.Label
        MeasuringSystem_Lamp           matlab.ui.control.Lamp
        IsolationDevice_EditField      matlab.ui.control.EditField
        IsolierungLabel                matlab.ui.control.Label
        IsolationDevice_Lamp           matlab.ui.control.Lamp
        KommunikationPanel             matlab.ui.container.Panel
        Can_EditField                  matlab.ui.control.EditField
        CANBusLabel                    matlab.ui.control.Label
        Can_Lamp                       matlab.ui.control.Lamp
        MesssystemTab                  matlab.ui.container.Tab
        TorPanel                       matlab.ui.container.Panel
        GeschlossenLabel               matlab.ui.control.Label
        TorLamp                        matlab.ui.control.Lamp
        TorButton                      matlab.ui.control.StateButton
        LichtPanel                     matlab.ui.container.Panel
        UVLampLabel                    matlab.ui.control.Label
        UVLamp                         matlab.ui.control.Lamp
        RotLampLabel                   matlab.ui.control.Label
        RotLamp                        matlab.ui.control.Lamp
        GrnLampLabel                   matlab.ui.control.Label
        GrunLamp                       matlab.ui.control.Lamp
        BlauLampLabel                  matlab.ui.control.Label
        BlauLamp                       matlab.ui.control.Lamp
        WeiLampLabel                   matlab.ui.control.Label
        WeissLamp                      matlab.ui.control.Lamp
        WeissSwitch                    matlab.ui.control.Switch
        BlauSwitch                     matlab.ui.control.Switch
        GrunSwitch                     matlab.ui.control.Switch
        RotSwitch                      matlab.ui.control.Switch
        UVSwitch                       matlab.ui.control.Switch
        KlassifiikationPanel           matlab.ui.container.Panel
        KlassifizierungdurchfhrenButton  matlab.ui.control.Button
        KameraPanel                    matlab.ui.container.Panel
        Weissbild_UIAxes               matlab.ui.control.UIAxes
        UVbild_UIAxes                  matlab.ui.control.UIAxes
        BilderaufnehmenButton          matlab.ui.control.Button
        BilderserieWUVaufnehmenButton  matlab.ui.control.Button
        FrderbandPanel_2               matlab.ui.container.Panel
        AktivLabel_3                   matlab.ui.control.Label
        MessForderbandAktiv_Lamp       matlab.ui.control.Lamp
        MessForderbandButton           matlab.ui.control.StateButton
        LichtschrankeLampLabel_2       matlab.ui.control.Label
        MeasLichtschrankeLamp          matlab.ui.control.Lamp
        WaagePanel                     matlab.ui.container.Panel
        WiegenButton                   matlab.ui.control.Button
        GewichtEditFieldLabel          matlab.ui.control.Label
        GewichtEditField               matlab.ui.control.NumericEditField
        TaraButton                     matlab.ui.control.Button
        IsolierungTab                  matlab.ui.container.Tab
        PosePanel                      matlab.ui.container.Panel
        xEditFieldLabel                matlab.ui.control.Label
        xEditField                     matlab.ui.control.NumericEditField
        yEditFieldLabel                matlab.ui.control.Label
        yEditField                     matlab.ui.control.NumericEditField
        zEditFieldLabel                matlab.ui.control.Label
        zEditField                     matlab.ui.control.NumericEditField
        ryEditFieldLabel               matlab.ui.control.Label
        ryEditField                    matlab.ui.control.NumericEditField
        rzEditFieldLabel               matlab.ui.control.Label
        rzEditField                    matlab.ui.control.NumericEditField
        rxEditFieldLabel               matlab.ui.control.Label
        rxEditField                    matlab.ui.control.NumericEditField
        GreiferPanel                   matlab.ui.container.Panel
        ZangeLinksGaugeLabel           matlab.ui.control.Label
        ZangeLinksGauge                matlab.ui.control.NinetyDegreeGauge
        ZangeRechtsGaugeLabel          matlab.ui.control.Label
        ZangeRechtsGauge               matlab.ui.control.NinetyDegreeGauge
        KontaktLampLabel               matlab.ui.control.Label
        KontaktLamp                    matlab.ui.control.Lamp
        OffsetEditFieldLabel           matlab.ui.control.Label
        OffsetEditField                matlab.ui.control.NumericEditField
        ZangeRechts_EditField          matlab.ui.control.NumericEditField
        ZangeLinks_EditField           matlab.ui.control.NumericEditField
        OpenCloseGripper_Button        matlab.ui.control.StateButton
        UnterdruckpumpePanel           matlab.ui.container.Panel
        SignalLampLabel                matlab.ui.control.Label
        VacuumSignalLamp               matlab.ui.control.Lamp
        VacuumAnAusButton              matlab.ui.control.StateButton
        FrderbandPanel_3               matlab.ui.container.Panel
        LichtschrankeLampLabel_3       matlab.ui.control.Label
        IsoLichtschrankeLamp           matlab.ui.control.Lamp
        IsoConvBeltButton              matlab.ui.control.StateButton
        AktivLabel_4                   matlab.ui.control.Label
        IsoForderbandAktiv_Lamp        matlab.ui.control.Lamp
        TrommelPanel                   matlab.ui.container.Panel
        DrumFeederAnAusButton          matlab.ui.control.StateButton
        AktivLabel_5                   matlab.ui.control.Label
        TrommelAktive_Lamp             matlab.ui.control.Lamp
        SpannungEditFieldLabel         matlab.ui.control.Label
        DrumFeederSpannungEditField    matlab.ui.control.NumericEditField
        ObjDetectCam_Axes              matlab.ui.control.UIAxes
        ObjDetectXSlider               matlab.ui.control.Slider
        ObjDetectYSlider               matlab.ui.control.Slider
        HorizontalSteuerungPanel       matlab.ui.container.Panel
        MarkiertePositionButton        matlab.ui.control.Button
        AblagePositionButton           matlab.ui.control.Button
        HomePositionButton             matlab.ui.control.Button
        VertikalSteuerungPanel         matlab.ui.container.Panel
        GreifebeneButton               matlab.ui.control.Button
        UnterdruckebeneButton          matlab.ui.control.Button
        BewegungsebeneButton           matlab.ui.control.Button
        HebeebeneButton                matlab.ui.control.Button
        AblageebeneButton              matlab.ui.control.Button
        BereichObjekterkennungPanel    matlab.ui.container.Panel
        XSpinnerLabel                  matlab.ui.control.Label
        ROI_X_Spinner                  matlab.ui.control.Spinner
        YSpinnerLabel                  matlab.ui.control.Label
        ROI_Y_Spinner                  matlab.ui.control.Spinner
        HheSpinnerLabel                matlab.ui.control.Label
        ROI_Hohe_Spinner               matlab.ui.control.Spinner
        BreiteSpinnerLabel             matlab.ui.control.Label
        ROI_Breite_Spinner             matlab.ui.control.Spinner
        ROIShow_Button                 matlab.ui.control.StateButton
        Vorschau_Button                matlab.ui.control.StateButton
        LoggingPanel                   matlab.ui.container.Panel
        FehlerCheckBox                 matlab.ui.control.CheckBox
        WarnungCheckBox                matlab.ui.control.CheckBox
        InfoCheckBox                   matlab.ui.control.CheckBox
        DebugCheckBox                  matlab.ui.control.CheckBox
        Log_UITable                    matlab.ui.control.Table
        angezeigteLogsSpinnerLabel     matlab.ui.control.Label
        AngezeigteLogs_Spinner         matlab.ui.control.Spinner
        FilterEditFieldLabel           matlab.ui.control.Label
        LogFilter_EditField            matlab.ui.control.EditField
        SteuerungPanel                 matlab.ui.container.Panel
        Start_Button                   matlab.ui.control.Button
        Stop_Button                    matlab.ui.control.Button
        ProbenLabel                    matlab.ui.control.Label
        Samples_Spinner                matlab.ui.control.Spinner
        ContinuousOperation_CheckBox   matlab.ui.control.CheckBox
        Process_EditField              matlab.ui.control.EditField
        ProzessLabel                   matlab.ui.control.Label
        Process_Lamp                   matlab.ui.control.Lamp
        Init_Button                    matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        RemainingSamplesSlash_Label    matlab.ui.control.Label
        RemainingSamples_EditField     matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        logger % Description
        allowed_log_levels = [1,1,1,1]; % Description
        binding_managers % Description
    end
    
    properties (Access = private)
        process % Description
        process_init_complete = false; % Description
        ObjDetectCam_Roi_Rectangle % Description
        ObjDetectCam_Roa_Rectangle % Description
        ObjDetectCam_X_Line % Description
        ObjDetectCam_Y_Line % Description
    end

    methods (Access = public)
    
        function evalAllBindings(app)
            binding_manager_names = fieldnames(app.binding_managers);
            for i = 1:length(binding_manager_names)
                binding_manager = app.binding_managers.(binding_manager_names{i});
                for j = 1:length(binding_manager.bindings)
                    binding_manager.bindings{j}.tryEval();
                end
            end
        end
                
        function logToTable(app, log_entry)
        % Fügt log_entry an das Ende (Oben) der Tabelle ein, wenn die
        % Kriterien erfüllt sind.
            if app.isAllowedLog(log_entry)
                max_logs = app.AngezeigteLogs_Spinner.Value;
                current_logs = length(app.Log_UITable.Data);
                % Falls maximale Anzahl an Logs erreicht ist, lösche den
                % letzten Eintrag
                if current_logs >= max_logs
                    app.Log_UITable.Data = app.Log_UITable.Data(1:end-1,:);
                end
                app.addLogToTable(log_entry);
            end
        end
        
        function addLogToTable(app, log_entry)
        % Fügt log_entry an das Ende (Oben) der Tabelle ein
            app.Log_UITable.Data = [
                        {app.logger.formatTime(log_entry),...
                        app.logger.formatLevel(log_entry),...
                        app.logger.formatSource(log_entry),...
                        app.logger.formatMessage(log_entry)};...
                        app.Log_UITable.Data];        
        end
        
        function is_allowed = isAllowedLog(app, log_entry)
        % Überprüft ob log_entry die Kriterien (Level & Filter) erfüllt
            is_allowed = false;
            if contains(lower(app.logger.formatSource(log_entry)), lower(app.LogFilter_EditField.Value))
                if app.allowed_log_levels(log_entry.level)
                    is_allowed = true;
                end
            end
        end
    
        function refreshLogTable(app)
            app.Log_UITable.Data = [];
            max_logs = app.AngezeigteLogs_Spinner.Value;
            current_logs = 0;
            log_ids = [];
            % Liste mit Logs erstellen, welche die Kriterien erfüllen
            for i = length(app.logger.log_store):-1:1
                if app.isAllowedLog(app.logger.log_store(i))
                    log_ids = [log_ids,i];
                    current_logs = current_logs + 1;
                    if current_logs == max_logs
                        break;
                    end
                end
            end
            % Gültige Logs in Tabelle hinzufügen
            for ii = length(log_ids):-1:1
                app.addLogToTable(app.logger.log_store(log_ids(ii)));
            end
        end
        
        function test(app)
            app.Start_ButtonPushed([])
            app.ContinuousOperation_CheckBox.Value = 1;
            app.Samples_Spinner.Value = 1;
            app.Start_ButtonPushed([])
        end
    end
    
    methods (Access = private)
        function initObjDetectCam_Axes(app)
            cam = app.process.isoDevice.robot.objDetection.cam;
            frame = snapshot(cam); 
            im = image(app.ObjDetectCam_Axes,zeros(size(frame),'uint8')); 
            axis(app.ObjDetectCam_Axes,'image');
            preview(cam,im)
            app.Vorschau_Button.Enable = false;
        end
        
        function refreshObjDetectCam_Axes(app)
            app.ROI_X_Spinner.Value = app.process.isoDevice.robot.objDetection.roi(2);
            app.ROI_Y_Spinner.Value = app.process.isoDevice.robot.objDetection.roi(1);
            app.ROI_Hohe_Spinner.Value = app.process.isoDevice.robot.objDetection.roi(3);
            app.ROI_Breite_Spinner.Value = app.process.isoDevice.robot.objDetection.roi(4);
        
            roi_active = app.ROIShow_Button.Value;
            if roi_active
                delete(app.ObjDetectCam_Roi_Rectangle)
                roi = app.process.isoDevice.robot.objDetection.roi;
                app.ObjDetectCam_Roi_Rectangle = rectangle(app.ObjDetectCam_Axes,'Position',[roi(2),roi(1),roi(4),roi(3)],'EdgeColor','r');
            end
        end
        
        function initObjekterkennungTab(app)
            app.ROI_X_Spinner.Value = app.process.isoDevice.robot.objDetection.roi(2);
            app.ROI_Y_Spinner.Value = app.process.isoDevice.robot.objDetection.roi(1);
            app.ROI_Hohe_Spinner.Value = app.process.isoDevice.robot.objDetection.roi(3);
            app.ROI_Breite_Spinner.Value = app.process.isoDevice.robot.objDetection.roi(4);
        end
        
        function initGUI(app)
            app.initObjekterkennungTab();
        end
        
        function loadSettings(app)
            try
                settings = load('Back-end\settings.mat');
            catch
                settings = load('settings.mat');
            end
            app.process.isoDevice.robot.roa = settings.process.isoDevice.robot.roa;
            app.process.isoDevice.robot.objDetection.roi = settings.process.isoDevice.robot.objDetection.roi;
            app.logger.debug('Einstellungen geladen'); 
        end
        
        function saveSettings(app)
            % process nicht mit app.process verwechseln!
            process.isoDevice.robot.roa = app.process.isoDevice.robot.roa;
            process.isoDevice.robot.objDetection.roi = app.process.isoDevice.robot.objDetection.roi;
            if exist('Back-end','dir')
                save('Back-end\settings.mat',process)
            else
                save('settings.mat',process)
            end
            this.logger.debug('Einstellungen gespeichert'); 
        end
        
        function updateConveyorBeltState_minor(app)
        if app.process.isoDevice.convBelt.getState ~= app.process.OFFLINE
            light_barrier_active = app.process.isoDevice.convBelt.lightBarrierActivated();
            if light_barrier_active
                app.IsoLichtschrankeLamp.Color = [0,1,0];
            else
                app.IsoLichtschrankeLamp.Color = [0.1,0.1,0.1];
            end
        end
        end
        
        function updateRobotState_minor(app)
        if app.process.isoDevice.robot.getState ~= app.process.OFFLINE
            pressure_on = app.process.isoDevice.robot.checkPressureSensor();
            if pressure_on
                app.VacuumSignalLamp.Color = [0,1,0];
            else
                app.VacuumSignalLamp.Color = [1,0,0];
            end
        end
        end
        
        function updateGripperState_minor(app)
        if app.process.isoDevice.robot.gripper.getState ~= app.process.OFFLINE
            [left_angle, right_angle] = app.process.isoDevice.robot.gripper.readAngles();
            app.ZangeLinksGauge.Value = left_angle;
            app.ZangeRechtsGauge.Value = right_angle;
            app.ZangeLinks_EditField.Value = left_angle;
            app.ZangeRechts_EditField.Value = right_angle;
            has_contact = app.process.isoDevice.robot.gripper.checkContact();
            if has_contact
                app.KontaktLamp.Color = [0,1,0];
            else
                app.KontaktLamp.Color = [0.1,0.1,0.1];
            end
        end
        end
        
        
        function setLighting(app)
            w = strcmp(app.WeissSwitch.Value,'1');
            b = strcmp(app.BlauSwitch.Value,'1');
            g = strcmp(app.GrunSwitch.Value,'1');
            r = strcmp(app.RotSwitch.Value,'1');
            uv = strcmp(app.UVSwitch.Value,'1');
            app.process.measSystem.cam.light.setLight(w,b,g,r,uv)
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, process, logger)
            if nargin < 2
                app.process = [];
            else
                app.process = process;
            end
            if nargin < 3
                app.logger = [];
            else
                logger.log_remote_fcn_handle = @app.logToTable;
                logger.log_remote_fcn_active = true;
                app.logger = logger;
                app.refreshLogTable();
            end
            addProcessGuiBindings(app)
            
            app.evalAllBindings()
        end

        % Menu selected function: HilfeMenu_3
        function HilfeMenuSelected(app, event)
            HelpGUI()
        end

        % Value changed function: DebugCheckBox
        function DebugCheckBoxValueChanged(app, event)
            app.allowed_log_levels(1) = app.DebugCheckBox.Value;
            app.refreshLogTable();
        end

        % Value changed function: InfoCheckBox
        function InfoCheckBoxValueChanged(app, event)
            app.allowed_log_levels(2) = app.InfoCheckBox.Value;
            app.refreshLogTable();
        end

        % Value changed function: WarnungCheckBox
        function WarnungCheckBoxValueChanged(app, event)
            app.allowed_log_levels(3) = app.WarnungCheckBox.Value;
            app.refreshLogTable();
        end

        % Value changed function: FehlerCheckBox
        function FehlerCheckBoxValueChanged(app, event)
            app.allowed_log_levels(4) = app.FehlerCheckBox.Value;
            app.refreshLogTable();
        end

        % Menu selected function: LoggerEinstellungenMenu
        function LoggerEinstellungenMenuSelected(app, event)
            LoggerSettingsGUI(app.logger) 
        end

        % Button pushed function: Start_Button
        function Start_ButtonPushed(app, event)
            app.Stop_Button.Enable = true;
            if app.ContinuousOperation_CheckBox.Value
                app.process.run(intmax);
            else
                app.process.run(app.Samples_Spinner.Value);
            end
        end

        % Value changed function: ContinuousOperation_CheckBox
        function ContinuousOperation_CheckBoxValueChanged(app, event)
            app.Samples_Spinner.Enable = ~app.ContinuousOperation_CheckBox.Value;
            app.RemainingSamples_EditField.Enable = ~app.ContinuousOperation_CheckBox.Value;
        end

        % Close request function: PMVAltpapierKlassifiziererUIFigure
        function PMVAltpapierKlassifiziererUIFigureCloseRequest(app, event)
            bm_array= struct2array(app.binding_managers);
            for i = 1:length(bm_array)
                stop(bm_array(i).timer);
            end
            delete(app)
        end

        % Value changed function: Vorschau_Button
        function Vorschau_ButtonValueChanged(app, event)
            app.initObjDetectCam_Axes();
            app.refreshObjDetectCam_Axes();
            app.ObjDetectXSlider.Limits = app.ObjDetectCam_Axes.XLim;
            app.ObjDetectYSlider.Limits = app.ObjDetectCam_Axes.YLim;
           
%             xlim_p = app.ObjDetectCam_Axes.XLim;
%             ylim_p = app.ObjDetectCam_Axes.YLim;
%             [x_min_cm, y_min_cm] = app.process.isoDevice.robot.objDetection.pixelToCoords(xlim_p(1),ylim_p(1));
%             [x_max_cm, y_max_cm] = app.process.isoDevice.robot.objDetection.pixelToCoords(xlim_p(2),ylim_p(2));
%             
%             app.ObjDetectXSlider.Limits = [x_min_cm, x_max_cm];
%             app.ObjDetectYSlider.Limits = [y_min_cm, y_max_cm];
        end

        % Value changed function: ROIShow_Button
        function ROIShow_ButtonValueChanged(app, event)
            app.refreshObjDetectCam_Axes;
        end

        % Value changed function: ROI_X_Spinner
        function ROI_X_SpinnerValueChanged(app, event)
            app.process.isoDevice.robot.objDetection.roi(2) = app.ROI_X_Spinner.Value;
            app.refreshObjDetectCam_Axes();
        end

        % Value changed function: ROI_Y_Spinner
        function ROI_Y_SpinnerValueChanged(app, event)
            app.process.isoDevice.robot.objDetection.roi(1) = app.ROI_Y_Spinner.Value;
            app.refreshObjDetectCam_Axes();
        end

        % Value changed function: ROI_Hohe_Spinner
        function ROI_Hohe_SpinnerValueChanged(app, event)
            app.process.isoDevice.robot.objDetection.roi(3) = app.ROI_Hohe_Spinner.Value;
            app.refreshObjDetectCam_Axes();
        end

        % Value changed function: ROI_Breite_Spinner
        function ROI_Breite_SpinnerValueChanged(app, event)
            app.process.isoDevice.robot.objDetection.roi(4) = app.ROI_Breite_Spinner.Value;
            app.refreshObjDetectCam_Axes();
        end

        % Callback function
        function ROA_X_SpinnerValueChanged(app, event)
            app.process.isoDevice.robot.roa(2) = app.ROA_X_Spinner.Value;
            app.refreshObjDetectCam_Axes();
        end

        % Callback function
        function ROA_Y_SpinnerValueChanged(app, event)
            app.process.isoDevice.robot.roa(1) = app.ROA_Y_Spinner.Value;
            app.refreshObjDetectCam_Axes();
        end

        % Callback function
        function ROA_Hohe_SpinnerValueChanged(app, event)
            app.process.isoDevice.robot.roa(3) = app.ROA_Hohe_Spinner.Value;
            app.refreshObjDetectCam_Axes();
        end

        % Callback function
        function ROA_Breite_SpinnerValueChanged(app, event)
            app.process.isoDevice.robot.roa(4) = app.ROA_Breite_Spinner.Value;
            app.refreshObjDetectCam_Axes();
        end

        % Callback function
        function ROAShow_ButtonValueChanged(app, event)
            app.refreshObjDetectCam_Axes;
        end

        % Menu selected function: ProzessEinstellungenladenMenu
        function ProzessEinstellungenladenMenuSelected(app, event)
            app.loadSettings();
        end

        % Menu selected function: ProzessEinstellungenspeichernMenu
        function ProzessEinstellungenspeichernMenuSelected(app, event)
            app.saveSettings();
        end

        % Value changed function: AngezeigteLogs_Spinner
        function AngezeigteLogs_SpinnerValueChanged(app, event)
            app.refreshLogTable();
        end

        % Value changed function: LogFilter_EditField
        function LogFilter_EditFieldValueChanged(app, event)
            app.refreshLogTable();
        end

        % Button pushed function: Stop_Button
        function Stop_ButtonPushed(app, event)
            app.process.remaining_iterations = 0; 
            app.process.isoDevice.convBelt.stop();
            app.process.isoDevice.drumFeeder.stop();
            app.process.measSystem.weighingBelt.stop();
            app.process.isoDevice.robot.deactivateVacuum();  
            app.Stop_Button.Enable = false;
            app.Start_Button.Enable = true;
        end

        % Value changed function: WeissSwitch
        function WeissSwitchValueChanged(app, event)
            app.setLighting();
        end

        % Value changed function: BlauSwitch
        function BlauSwitchValueChanged(app, event)
            app.setLighting();
        end

        % Value changed function: GrunSwitch
        function GrunSwitchValueChanged(app, event)
            app.setLighting();
        end

        % Value changed function: RotSwitch
        function RotSwitchValueChanged(app, event)
            app.setLighting();
        end

        % Value changed function: UVSwitch
        function UVSwitchValueChanged(app, event)
            app.setLighting();
        end

        % Value changed function: DrumFeederSpannungEditField
        function DrumFeederSpannungEditFieldValueChanged(app, event)
            app.process.isoDevice.drumFeeder.setVoltage(app.DrumFeederSpannungEditField.Value);
            
        end

        % Value changed function: DrumFeederAnAusButton
        function DrumFeederAnAusButtonValueChanged(app, event)
            if app.DrumFeederAnAusButton.Value
                app.DrumFeederAnAusButton.Text = 'Stop';
                app.process.isoDevice.drumFeeder.start();
            else
                app.process.isoDevice.drumFeeder.stop();
                app.DrumFeederAnAusButton.Text = 'Start';
            end
        end

        % Value changed function: IsoConvBeltButton
        function IsoConvBeltButtonValueChanged(app, event)
            if app.IsoConvBeltButton.Value
                app.IsoConvBeltButton.Text = 'Stop';
                app.process.isoDevice.convBelt.start();
            else
                app.process.isoDevice.convBelt.stop();
                app.IsoConvBeltButton.Text = 'Start';
            end
        end

        % Value changed function: OpenCloseGripper_Button
        function OpenCloseGripper_ButtonValueChanged(app, event)
            if app.OpenCloseGripper_Button.Value
                app.OpenCloseGripper_Button.Text = 'Öffnen';
                app.process.isoDevice.robot.gripper.close();
            else
                app.process.isoDevice.robot.gripper.open();
                app.OpenCloseGripper_Button.Text = 'Schließen';
            end
        end

        % Value changed function: OffsetEditField
        function OffsetEditFieldValueChanged(app, event)
            app.process.isoDevice.robot.gripper.changeOffset(app.OffsetEditField.Value);  
        end

        % Button pushed function: Init_Button
        function Init_ButtonPushed(app, event)
                app.Init_Button.Enable = false;
                app.process.init();
                app.Start_Button.Enable = true;
                app.Start_Button.Text = 'Start';
                app.process_init_complete = true;
                app.initGUI();
        end

        % Value changed function: VacuumAnAusButton
        function VacuumAnAusButtonValueChanged(app, event)
            if app.VacuumAnAusButton.Value
                app.process.isoDevice.robot.activateVacuum();
                app.VacuumAnAusButton.Text = 'Deaktivieren';
            else
                app.process.isoDevice.robot.deactivateVacuum();
                app.VacuumAnAusButton.Text = 'Aktivieren';
            end
        end

        % Value changed function: TorButton
        function TorButtonValueChanged(app, event)
            if app.TorButton.Value
                app.process.measSystem.closeGate();
            else
                app.process.measSystem.openGate();
            end
        end

        % Value changed function: MessForderbandButton
        function MessForderbandButtonValueChanged(app, event)
            if app.MessForderbandButton.Value
                app.process.measSystem.weighingBelt.start();
            else
                app.process.measSystem.weighingBelt.stop();
            end
        end

        % Button pushed function: WiegenButton
        function WiegenButtonPushed(app, event)
            app.process.measSystem.scale.awaitMass();
        end

        % Button pushed function: TaraButton
        function TaraButtonPushed(app, event)
            app.process.measSystem.scale.zero(); 
        end

        % Button pushed function: BilderaufnehmenButton
        function BilderaufnehmenButtonPushed(app, event)
            imshow(app.process.measSystem.cam.requestFoto());
        end

        % Button pushed function: BilderserieWUVaufnehmenButton
        function BilderserieWUVaufnehmenButtonPushed(app, event)
            app.process.measSystem.cam.takePhotos();
            image(Weissbild_UIAxes,app.process.measSystem.cam.imgRGB)
            image(UVbild_UIAxes,app.process.measSystem.cam.imgUV)
        end

        % Value changing function: ObjDetectYSlider
        function ObjDetectYSliderValueChanging(app, event)
            y = event.Value;
            x_lim = app.ObjDetectXSlider.Limits;
            y_lim = app.ObjDetectYSlider.Limits;
%             [x_lim_p(1), y] = app.process.isoDevice.robot.objDetection.coordsToPixel(x_lim(1),y);
%             [x_lim_p(2), y] = app.process.isoDevice.robot.objDetection.coordsToPixel(x_lim(2),y);
            try
                delete(app.ObjDetectCam_Y_Line)
            end
            disp(y)
            y = y_lim(2)-y;
            app.ObjDetectCam_Y_Line = line(app.ObjDetectCam_Axes,[x_lim(1),x_lim(2)],[y,y],'Color','b');
        end

        % Value changing function: ObjDetectXSlider
        function ObjDetectXSliderValueChanging(app, event)
            x = event.Value;
            y_lim = app.ObjDetectYSlider.Limits;
%             [x, y_lim_p(1)] = app.process.isoDevice.robot.objDetection.coordsToPixel(x,y_lim(1));
%             [x, y_lim_p(2)] = app.process.isoDevice.robot.objDetection.coordsToPixel(x,y_lim(2));
            try
                delete(app.ObjDetectCam_X_Line)
            end
            app.ObjDetectCam_X_Line = line(app.ObjDetectCam_Axes,[x,x],[y_lim(1),y_lim(2)],'Color','b');
        end

        % Button pushed function: MarkiertePositionButton
        function MarkiertePositionButtonPushed(app, event)
            x_p = app.ObjDetectXSlider.Value;
            y_p = app.ObjDetectYSlider.Value;
            y_lim = app.ObjDetectYSlider.Limits;
            y_p = y_lim(2)-y_p;
            [x, y] = app.process.isoDevice.robot.objDetection.pixelToCoords(x_p,y_p);
            app.process.isoDevice.robot.moveTo(x,y)
        end

        % Button pushed function: HomePositionButton
        function HomePositionButtonPushed(app, event)
            app.process.isoDevice.robot.moveToHomePosition;
        end

        % Button pushed function: AblagePositionButton
        function AblagePositionButtonPushed(app, event)
            app.process.isoDevice.robot.moveToDroppingPosition;
        end

        % Button pushed function: BewegungsebeneButton
        function BewegungsebeneButtonPushed(app, event)
            app.process.isoDevice.robot.heaveToMovementHeight;
        end

        % Button pushed function: HebeebeneButton
        function HebeebeneButtonPushed(app, event)
            app.process.isoDevice.robot.heaveToLiftingHeight;
        end

        % Button pushed function: GreifebeneButton
        function GreifebeneButtonPushed(app, event)
            app.process.isoDevice.robot.heaveToGrippingHeight;
        end

        % Button pushed function: UnterdruckebeneButton
        function UnterdruckebeneButtonPushed(app, event)
            app.process.isoDevice.robot.heaveToVacuumHeight;
        end

        % Button pushed function: AblageebeneButton
        function AblageebeneButtonPushed(app, event)
            app.process.isoDevice.robot.heaveToDroppingHeight;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PMVAltpapierKlassifiziererUIFigure = uifigure('Visible', 'off');
            app.PMVAltpapierKlassifiziererUIFigure.Name = 'PMV Altpapier Klassifizierer';
            app.PMVAltpapierKlassifiziererUIFigure.CloseRequestFcn = createCallbackFcn(app, @PMVAltpapierKlassifiziererUIFigureCloseRequest, true);
            app.OptionenMenu = uimenu(app.PMVAltpapierKlassifiziererUIFigure);
            app.OptionenMenu.Text = 'Optionen';
            app.ProzessEinstellungenladenMenu = uimenu(app.OptionenMenu);
            app.ProzessEinstellungenladenMenu.MenuSelectedFcn = createCallbackFcn(app, @ProzessEinstellungenladenMenuSelected, true);
            app.ProzessEinstellungenladenMenu.Enable = 'off';
            app.ProzessEinstellungenladenMenu.Text = 'Prozess Einstellungen laden...';
            app.ProzessEinstellungenspeichernMenu = uimenu(app.OptionenMenu);
            app.ProzessEinstellungenspeichernMenu.MenuSelectedFcn = createCallbackFcn(app, @ProzessEinstellungenspeichernMenuSelected, true);
            app.ProzessEinstellungenspeichernMenu.Enable = 'off';
            app.ProzessEinstellungenspeichernMenu.Text = 'Prozess Einstellungen speichern...';
            app.LoggerEinstellungenMenu = uimenu(app.OptionenMenu);
            app.LoggerEinstellungenMenu.MenuSelectedFcn = createCallbackFcn(app, @LoggerEinstellungenMenuSelected, true);
            app.LoggerEinstellungenMenu.Text = 'Logger - Einstellungen...';
            app.HilfeMenu_2 = uimenu(app.PMVAltpapierKlassifiziererUIFigure);
            app.HilfeMenu_2.Text = 'Hilfe';
            app.HilfeMenu_3 = uimenu(app.HilfeMenu_2);
            app.HilfeMenu_3.MenuSelectedFcn = createCallbackFcn(app, @HilfeMenuSelected, true);
            app.HilfeMenu_3.Text = 'Hilfe';
            app.TabGroup = uitabgroup(app.PMVAltpapierKlassifiziererUIFigure);
            app.GesamtprozessTab = uitab(app.TabGroup);
            app.GesamtprozessTab.Title = 'Gesamtprozess';
            app.IsolierungPanel = uipanel(app.GesamtprozessTab);
            app.IsolierungPanel.Title = 'Isolierung';
            app.TrommelLampLabel = uilabel(app.IsolierungPanel);
            app.TrommelLampLabel.Text = 'Trommel';
            app.DrumFeeder_Lamp = uilamp(app.IsolierungPanel);
            app.ConvBelt_EditField = uieditfield(app.IsolierungPanel, 'text');
            app.FrderbandLamp_3Label = uilabel(app.IsolierungPanel);
            app.FrderbandLamp_3Label.Text = 'Förderband';
            app.ConvBelt_Lamp = uilamp(app.IsolierungPanel);
            app.Robot_EditField = uieditfield(app.IsolierungPanel, 'text');
            app.RoboterLampLabel = uilabel(app.IsolierungPanel);
            app.RoboterLampLabel.Text = 'Roboter';
            app.Robot_Lamp = uilamp(app.IsolierungPanel);
            app.DrumFeeder_EditField = uieditfield(app.IsolierungPanel, 'text');
            app.Gripper_EditField = uieditfield(app.IsolierungPanel, 'text');
            app.GreiferLampLabel = uilabel(app.IsolierungPanel);
            app.GreiferLampLabel.Text = 'Greifer';
            app.Gripper_Lamp = uilamp(app.IsolierungPanel);
            app.ObjectDetection_EditField = uieditfield(app.IsolierungPanel, 'text');
            app.ObjekterkennungLamp_2Label = uilabel(app.IsolierungPanel);
            app.ObjekterkennungLamp_2Label.Text = 'Objekterkennung';
            app.ObjectDetection_Lamp = uilamp(app.IsolierungPanel);
            app.MesssystemPanel = uipanel(app.GesamtprozessTab);
            app.MesssystemPanel.Title = 'Messsystem';
            app.WeighingBelt_EditField = uieditfield(app.MesssystemPanel, 'text');
            app.FrderbandLamp_4Label = uilabel(app.MesssystemPanel);
            app.FrderbandLamp_4Label.Text = 'Förderband';
            app.WeighingBelt_Lamp = uilamp(app.MesssystemPanel);
            app.Camera_EditField = uieditfield(app.MesssystemPanel, 'text');
            app.Scale_EditField = uieditfield(app.MesssystemPanel, 'text');
            app.KameraLampLabel = uilabel(app.MesssystemPanel);
            app.KameraLampLabel.Text = 'Kamera';
            app.Camera_Lamp = uilamp(app.MesssystemPanel);
            app.WaageLampLabel = uilabel(app.MesssystemPanel);
            app.WaageLampLabel.Text = 'Waage';
            app.Scale_Lamp = uilamp(app.MesssystemPanel);
            app.Light_EditField = uieditfield(app.MesssystemPanel, 'text');
            app.LichtLabel = uilabel(app.MesssystemPanel);
            app.LichtLabel.Text = 'Licht';
            app.Light_Lamp = uilamp(app.MesssystemPanel);
            app.MeasuringSystem_EditField = uieditfield(app.GesamtprozessTab, 'text');
            app.MesssystemLabel = uilabel(app.GesamtprozessTab);
            app.MesssystemLabel.Text = 'Messsystem';
            app.MeasuringSystem_Lamp = uilamp(app.GesamtprozessTab);
            app.IsolationDevice_EditField = uieditfield(app.GesamtprozessTab, 'text');
            app.IsolierungLabel = uilabel(app.GesamtprozessTab);
            app.IsolierungLabel.Text = 'Isolierung';
            app.IsolationDevice_Lamp = uilamp(app.GesamtprozessTab);
            app.KommunikationPanel = uipanel(app.GesamtprozessTab);
            app.KommunikationPanel.Title = 'Kommunikation';
            app.Can_EditField = uieditfield(app.KommunikationPanel, 'text');
            app.CANBusLabel = uilabel(app.KommunikationPanel);
            app.CANBusLabel.Text = 'CAN-Bus';
            app.Can_Lamp = uilamp(app.KommunikationPanel);
            app.MesssystemTab = uitab(app.TabGroup);
            app.MesssystemTab.Title = 'Messsystem';
            app.TorPanel = uipanel(app.MesssystemTab);
            app.TorPanel.Title = 'Tor';
            app.GeschlossenLabel = uilabel(app.TorPanel);
            app.GeschlossenLabel.Text = 'Geschlossen';
            app.TorLamp = uilamp(app.TorPanel);
            app.TorButton = uibutton(app.TorPanel, 'state');
            app.TorButton.ValueChangedFcn = createCallbackFcn(app, @TorButtonValueChanged, true);
            app.TorButton.Text = 'Auf/Zu';
            app.LichtPanel = uipanel(app.MesssystemTab);
            app.LichtPanel.Title = 'Licht';
            app.UVLampLabel = uilabel(app.LichtPanel);
            app.UVLampLabel.Text = 'UV';
            app.UVLamp = uilamp(app.LichtPanel);
            app.RotLampLabel = uilabel(app.LichtPanel);
            app.RotLampLabel.Text = 'Rot';
            app.RotLamp = uilamp(app.LichtPanel);
            app.GrnLampLabel = uilabel(app.LichtPanel);
            app.GrnLampLabel.Text = 'Grün';
            app.GrunLamp = uilamp(app.LichtPanel);
            app.BlauLampLabel = uilabel(app.LichtPanel);
            app.BlauLampLabel.Text = 'Blau';
            app.BlauLamp = uilamp(app.LichtPanel);
            app.WeiLampLabel = uilabel(app.LichtPanel);
            app.WeiLampLabel.Text = 'Weiß';
            app.WeissLamp = uilamp(app.LichtPanel);
            app.WeissSwitch = uiswitch(app.LichtPanel, 'slider');
            app.WeissSwitch.Items = {'0', '1'};
            app.WeissSwitch.ValueChangedFcn = createCallbackFcn(app, @WeissSwitchValueChanged, true);
            app.WeissSwitch.Value = '0';
            app.BlauSwitch = uiswitch(app.LichtPanel, 'slider');
            app.BlauSwitch.Items = {'0', '1'};
            app.BlauSwitch.ValueChangedFcn = createCallbackFcn(app, @BlauSwitchValueChanged, true);
            app.BlauSwitch.Value = '0';
            app.GrunSwitch = uiswitch(app.LichtPanel, 'slider');
            app.GrunSwitch.Items = {'0', '1'};
            app.GrunSwitch.ValueChangedFcn = createCallbackFcn(app, @GrunSwitchValueChanged, true);
            app.GrunSwitch.Value = '0';
            app.RotSwitch = uiswitch(app.LichtPanel, 'slider');
            app.RotSwitch.Items = {'0', '1'};
            app.RotSwitch.ValueChangedFcn = createCallbackFcn(app, @RotSwitchValueChanged, true);
            app.RotSwitch.Value = '0';
            app.UVSwitch = uiswitch(app.LichtPanel, 'slider');
            app.UVSwitch.Items = {'0', '1'};
            app.UVSwitch.ValueChangedFcn = createCallbackFcn(app, @UVSwitchValueChanged, true);
            app.UVSwitch.Value = '0';
            app.KlassifiikationPanel = uipanel(app.MesssystemTab);
            app.KlassifiikationPanel.Title = 'Klassifiikation';
            app.KlassifizierungdurchfhrenButton = uibutton(app.KlassifiikationPanel, 'push');
            app.KlassifizierungdurchfhrenButton.Enable = 'off';
            app.KlassifizierungdurchfhrenButton.Tooltip = {'Derzeit nicht verfügbar'; ' benötigt Implementierung'};
            app.KlassifizierungdurchfhrenButton.Text = 'Klassifizierung durchführen';
            app.KameraPanel = uipanel(app.MesssystemTab);
            app.KameraPanel.Title = 'Kamera';
            app.Weissbild_UIAxes = uiaxes(app.KameraPanel);
            title(app.Weissbild_UIAxes, 'Weiß-Bild')
            xlabel(app.Weissbild_UIAxes, 'X')
            ylabel(app.Weissbild_UIAxes, 'Y')
            app.UVbild_UIAxes = uiaxes(app.KameraPanel);
            title(app.UVbild_UIAxes, 'UV-Bild')
            xlabel(app.UVbild_UIAxes, 'X')
            ylabel(app.UVbild_UIAxes, 'Y')
            app.BilderaufnehmenButton = uibutton(app.KameraPanel, 'push');
            app.BilderaufnehmenButton.ButtonPushedFcn = createCallbackFcn(app, @BilderaufnehmenButtonPushed, true);
            app.BilderaufnehmenButton.Text = 'Bilder aufnehmen';
            app.BilderserieWUVaufnehmenButton = uibutton(app.KameraPanel, 'push');
            app.BilderserieWUVaufnehmenButton.ButtonPushedFcn = createCallbackFcn(app, @BilderserieWUVaufnehmenButtonPushed, true);
            app.BilderserieWUVaufnehmenButton.Text = {'Bilderserie'; '( W + UV )'; 'aufnehmen'};
            app.FrderbandPanel_2 = uipanel(app.MesssystemTab);
            app.FrderbandPanel_2.Title = 'Förderband';
            app.AktivLabel_3 = uilabel(app.FrderbandPanel_2);
            app.AktivLabel_3.Text = 'Aktiv';
            app.MessForderbandAktiv_Lamp = uilamp(app.FrderbandPanel_2);
            app.MessForderbandButton = uibutton(app.FrderbandPanel_2, 'state');
            app.MessForderbandButton.ValueChangedFcn = createCallbackFcn(app, @MessForderbandButtonValueChanged, true);
            app.MessForderbandButton.Text = 'An/Aus';
            app.LichtschrankeLampLabel_2 = uilabel(app.FrderbandPanel_2);
            app.LichtschrankeLampLabel_2.Text = 'Lichtschranke';
            app.MeasLichtschrankeLamp = uilamp(app.FrderbandPanel_2);
            app.WaagePanel = uipanel(app.MesssystemTab);
            app.WaagePanel.Title = 'Waage';
            app.WiegenButton = uibutton(app.WaagePanel, 'push');
            app.WiegenButton.ButtonPushedFcn = createCallbackFcn(app, @WiegenButtonPushed, true);
            app.WiegenButton.Enable = 'off';
            app.WiegenButton.Tooltip = {'Deaktiviert'; ' da die Waage derzeit nur Prozessgesteuert ist'};
            app.WiegenButton.Text = 'Wiegen';
            app.GewichtEditFieldLabel = uilabel(app.WaagePanel);
            app.GewichtEditFieldLabel.Text = 'Gewicht';
            app.GewichtEditField = uieditfield(app.WaagePanel, 'numeric');
            app.TaraButton = uibutton(app.WaagePanel, 'push');
            app.TaraButton.ButtonPushedFcn = createCallbackFcn(app, @TaraButtonPushed, true);
            app.TaraButton.Text = 'Tara';
            app.IsolierungTab = uitab(app.TabGroup);
            app.IsolierungTab.Title = 'Isolierung';
            app.PosePanel = uipanel(app.IsolierungTab);
            app.PosePanel.Title = 'Pose';
            app.xEditFieldLabel = uilabel(app.PosePanel);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.PosePanel, 'numeric');
            app.xEditField.ValueDisplayFormat = '%3.1f';
            app.xEditField.Editable = 'off';
            app.yEditFieldLabel = uilabel(app.PosePanel);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.PosePanel, 'numeric');
            app.yEditField.ValueDisplayFormat = '%3.1f';
            app.yEditField.Editable = 'off';
            app.zEditFieldLabel = uilabel(app.PosePanel);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.PosePanel, 'numeric');
            app.zEditField.ValueDisplayFormat = '%3.1f';
            app.zEditField.Editable = 'off';
            app.ryEditFieldLabel = uilabel(app.PosePanel);
            app.ryEditFieldLabel.Text = 'ry';
            app.ryEditField = uieditfield(app.PosePanel, 'numeric');
            app.ryEditField.ValueDisplayFormat = '%3.1f';
            app.ryEditField.Editable = 'off';
            app.rzEditFieldLabel = uilabel(app.PosePanel);
            app.rzEditFieldLabel.Text = 'rz';
            app.rzEditField = uieditfield(app.PosePanel, 'numeric');
            app.rzEditField.ValueDisplayFormat = '%3.1f';
            app.rzEditField.Editable = 'off';
            app.rxEditFieldLabel = uilabel(app.PosePanel);
            app.rxEditFieldLabel.Text = 'rx';
            app.rxEditField = uieditfield(app.PosePanel, 'numeric');
            app.rxEditField.ValueDisplayFormat = '%3.1f';
            app.rxEditField.Editable = 'off';
            app.GreiferPanel = uipanel(app.IsolierungTab);
            app.GreiferPanel.Title = 'Greifer';
            app.ZangeLinksGaugeLabel = uilabel(app.GreiferPanel);
            app.ZangeLinksGaugeLabel.Text = 'Zange Links';
            app.ZangeLinksGauge = uigauge(app.GreiferPanel, 'ninetydegree');
            app.ZangeLinksGauge.Limits = [0 1];
            app.ZangeLinksGauge.MajorTicks = [0 0.25 0.5 0.75 1];
            app.ZangeLinksGauge.MajorTickLabels = {'0', '.25', '.5', '.75', '1'};
            app.ZangeLinksGauge.ScaleDirection = 'counterclockwise';
            app.ZangeRechtsGaugeLabel = uilabel(app.GreiferPanel);
            app.ZangeRechtsGaugeLabel.Text = 'Zange Rechts';
            app.ZangeRechtsGauge = uigauge(app.GreiferPanel, 'ninetydegree');
            app.ZangeRechtsGauge.Limits = [0 1];
            app.ZangeRechtsGauge.MajorTicks = [0 0.25 0.5 0.75 1];
            app.ZangeRechtsGauge.MajorTickLabels = {'0', '.25', '.5', '.75', '1'};
            app.KontaktLampLabel = uilabel(app.GreiferPanel);
            app.KontaktLampLabel.Text = 'Kontakt';
            app.KontaktLamp = uilamp(app.GreiferPanel);
            app.OffsetEditFieldLabel = uilabel(app.GreiferPanel);
            app.OffsetEditFieldLabel.Text = 'Offset';
            app.OffsetEditField = uieditfield(app.GreiferPanel, 'numeric');
            app.OffsetEditField.Limits = [-0.1 0.1];
            app.OffsetEditField.ValueChangedFcn = createCallbackFcn(app, @OffsetEditFieldValueChanged, true);
            app.ZangeRechts_EditField = uieditfield(app.GreiferPanel, 'numeric');
            app.ZangeRechts_EditField.Limits = [0 1];
            app.ZangeRechts_EditField.Editable = 'off';
            app.ZangeLinks_EditField = uieditfield(app.GreiferPanel, 'numeric');
            app.ZangeLinks_EditField.Limits = [0 1];
            app.ZangeLinks_EditField.Editable = 'off';
            app.OpenCloseGripper_Button = uibutton(app.GreiferPanel, 'state');
            app.OpenCloseGripper_Button.ValueChangedFcn = createCallbackFcn(app, @OpenCloseGripper_ButtonValueChanged, true);
            app.OpenCloseGripper_Button.Text = 'Öffnen/Schließen';
            app.UnterdruckpumpePanel = uipanel(app.IsolierungTab);
            app.UnterdruckpumpePanel.Title = 'Unterdruckpumpe';
            app.SignalLampLabel = uilabel(app.UnterdruckpumpePanel);
            app.SignalLampLabel.Text = 'Signal';
            app.VacuumSignalLamp = uilamp(app.UnterdruckpumpePanel);
            app.VacuumAnAusButton = uibutton(app.UnterdruckpumpePanel, 'state');
            app.VacuumAnAusButton.ValueChangedFcn = createCallbackFcn(app, @VacuumAnAusButtonValueChanged, true);
            app.VacuumAnAusButton.Text = 'An/Aus';
            app.FrderbandPanel_3 = uipanel(app.IsolierungTab);
            app.FrderbandPanel_3.Title = 'Förderband';
            app.LichtschrankeLampLabel_3 = uilabel(app.FrderbandPanel_3);
            app.LichtschrankeLampLabel_3.Text = 'Lichtschranke';
            app.IsoLichtschrankeLamp = uilamp(app.FrderbandPanel_3);
            app.IsoConvBeltButton = uibutton(app.FrderbandPanel_3, 'state');
            app.IsoConvBeltButton.ValueChangedFcn = createCallbackFcn(app, @IsoConvBeltButtonValueChanged, true);
            app.IsoConvBeltButton.Text = 'An/Aus';
            app.AktivLabel_4 = uilabel(app.FrderbandPanel_3);
            app.AktivLabel_4.Text = 'Aktiv';
            app.IsoForderbandAktiv_Lamp = uilamp(app.FrderbandPanel_3);
            app.TrommelPanel = uipanel(app.IsolierungTab);
            app.TrommelPanel.Title = 'Trommel';
            app.DrumFeederAnAusButton = uibutton(app.TrommelPanel, 'state');
            app.DrumFeederAnAusButton.ValueChangedFcn = createCallbackFcn(app, @DrumFeederAnAusButtonValueChanged, true);
            app.DrumFeederAnAusButton.Text = 'An/Aus';
            app.AktivLabel_5 = uilabel(app.TrommelPanel);
            app.AktivLabel_5.Text = 'Aktiv';
            app.TrommelAktive_Lamp = uilamp(app.TrommelPanel);
            app.SpannungEditFieldLabel = uilabel(app.TrommelPanel);
            app.SpannungEditFieldLabel.Text = 'Spannung';
            app.DrumFeederSpannungEditField = uieditfield(app.TrommelPanel, 'numeric');
            app.DrumFeederSpannungEditField.Limits = [0 5];
            app.DrumFeederSpannungEditField.ValueChangedFcn = createCallbackFcn(app, @DrumFeederSpannungEditFieldValueChanged, true);
            app.ObjDetectCam_Axes = uiaxes(app.IsolierungTab);
            title(app.ObjDetectCam_Axes, 'Title')
            xlabel(app.ObjDetectCam_Axes, 'X')
            ylabel(app.ObjDetectCam_Axes, 'Y')
            app.ObjDetectXSlider = uislider(app.IsolierungTab);
            app.ObjDetectXSlider.ValueChangingFcn = createCallbackFcn(app, @ObjDetectXSliderValueChanging, true);
            app.ObjDetectYSlider = uislider(app.IsolierungTab);
            app.ObjDetectYSlider.ValueChangingFcn = createCallbackFcn(app, @ObjDetectYSliderValueChanging, true);
            app.HorizontalSteuerungPanel = uipanel(app.IsolierungTab);
            app.HorizontalSteuerungPanel.Title = 'Horizontal-Steuerung';
            app.MarkiertePositionButton = uibutton(app.HorizontalSteuerungPanel, 'push');
            app.MarkiertePositionButton.ButtonPushedFcn = createCallbackFcn(app, @MarkiertePositionButtonPushed, true);
            app.MarkiertePositionButton.Text = 'Markierte Position';
            app.AblagePositionButton = uibutton(app.HorizontalSteuerungPanel, 'push');
            app.AblagePositionButton.ButtonPushedFcn = createCallbackFcn(app, @AblagePositionButtonPushed, true);
            app.AblagePositionButton.Text = 'Ablage-Position';
            app.HomePositionButton = uibutton(app.HorizontalSteuerungPanel, 'push');
            app.HomePositionButton.ButtonPushedFcn = createCallbackFcn(app, @HomePositionButtonPushed, true);
            app.HomePositionButton.Text = 'Home-Position';
            app.VertikalSteuerungPanel = uipanel(app.IsolierungTab);
            app.VertikalSteuerungPanel.Title = 'Vertikal-Steuerung';
            app.GreifebeneButton = uibutton(app.VertikalSteuerungPanel, 'push');
            app.GreifebeneButton.ButtonPushedFcn = createCallbackFcn(app, @GreifebeneButtonPushed, true);
            app.GreifebeneButton.Text = 'Greifebene';
            app.UnterdruckebeneButton = uibutton(app.VertikalSteuerungPanel, 'push');
            app.UnterdruckebeneButton.ButtonPushedFcn = createCallbackFcn(app, @UnterdruckebeneButtonPushed, true);
            app.UnterdruckebeneButton.Text = 'Unterdruckebene';
            app.BewegungsebeneButton = uibutton(app.VertikalSteuerungPanel, 'push');
            app.BewegungsebeneButton.ButtonPushedFcn = createCallbackFcn(app, @BewegungsebeneButtonPushed, true);
            app.BewegungsebeneButton.Text = 'Bewegungsebene';
            app.HebeebeneButton = uibutton(app.VertikalSteuerungPanel, 'push');
            app.HebeebeneButton.ButtonPushedFcn = createCallbackFcn(app, @HebeebeneButtonPushed, true);
            app.HebeebeneButton.Text = 'Hebeebene';
            app.AblageebeneButton = uibutton(app.VertikalSteuerungPanel, 'push');
            app.AblageebeneButton.ButtonPushedFcn = createCallbackFcn(app, @AblageebeneButtonPushed, true);
            app.AblageebeneButton.Text = 'Ablageebene';
            app.BereichObjekterkennungPanel = uipanel(app.IsolierungTab);
            app.BereichObjekterkennungPanel.Title = 'Objekterkennung';
            app.XSpinnerLabel = uilabel(app.BereichObjekterkennungPanel);
            app.XSpinnerLabel.Text = 'X';
            app.ROI_X_Spinner = uispinner(app.BereichObjekterkennungPanel);
            app.ROI_X_Spinner.ValueChangedFcn = createCallbackFcn(app, @ROI_X_SpinnerValueChanged, true);
            app.YSpinnerLabel = uilabel(app.BereichObjekterkennungPanel);
            app.YSpinnerLabel.Text = 'Y';
            app.ROI_Y_Spinner = uispinner(app.BereichObjekterkennungPanel);
            app.ROI_Y_Spinner.ValueChangedFcn = createCallbackFcn(app, @ROI_Y_SpinnerValueChanged, true);
            app.HheSpinnerLabel = uilabel(app.BereichObjekterkennungPanel);
            app.HheSpinnerLabel.Text = 'Höhe';
            app.ROI_Hohe_Spinner = uispinner(app.BereichObjekterkennungPanel);
            app.ROI_Hohe_Spinner.ValueChangedFcn = createCallbackFcn(app, @ROI_Hohe_SpinnerValueChanged, true);
            app.BreiteSpinnerLabel = uilabel(app.BereichObjekterkennungPanel);
            app.BreiteSpinnerLabel.Text = 'Breite';
            app.ROI_Breite_Spinner = uispinner(app.BereichObjekterkennungPanel);
            app.ROI_Breite_Spinner.ValueChangedFcn = createCallbackFcn(app, @ROI_Breite_SpinnerValueChanged, true);
            app.ROIShow_Button = uibutton(app.BereichObjekterkennungPanel, 'state');
            app.ROIShow_Button.ValueChangedFcn = createCallbackFcn(app, @ROIShow_ButtonValueChanged, true);
            app.ROIShow_Button.Text = 'Bereich anzeigen';
            app.Vorschau_Button = uibutton(app.BereichObjekterkennungPanel, 'state');
            app.Vorschau_Button.ValueChangedFcn = createCallbackFcn(app, @Vorschau_ButtonValueChanged, true);
            app.Vorschau_Button.Text = 'Vorschau starten';
            app.LoggingPanel = uipanel(app.PMVAltpapierKlassifiziererUIFigure);
            app.LoggingPanel.Title = 'Logging';
            app.FehlerCheckBox = uicheckbox(app.LoggingPanel);
            app.FehlerCheckBox.ValueChangedFcn = createCallbackFcn(app, @FehlerCheckBoxValueChanged, true);
            app.FehlerCheckBox.Text = 'Fehler';
            app.FehlerCheckBox.Value = true;
            app.WarnungCheckBox = uicheckbox(app.LoggingPanel);
            app.WarnungCheckBox.ValueChangedFcn = createCallbackFcn(app, @WarnungCheckBoxValueChanged, true);
            app.WarnungCheckBox.Text = 'Warnung';
            app.WarnungCheckBox.Value = true;
            app.InfoCheckBox = uicheckbox(app.LoggingPanel);
            app.InfoCheckBox.ValueChangedFcn = createCallbackFcn(app, @InfoCheckBoxValueChanged, true);
            app.InfoCheckBox.Text = 'Info';
            app.InfoCheckBox.Value = true;
            app.DebugCheckBox = uicheckbox(app.LoggingPanel);
            app.DebugCheckBox.ValueChangedFcn = createCallbackFcn(app, @DebugCheckBoxValueChanged, true);
            app.DebugCheckBox.Text = 'Debug';
            app.DebugCheckBox.Value = true;
            app.Log_UITable = uitable(app.LoggingPanel);
            app.Log_UITable.ColumnName = {'Zeit'; 'Level'; 'Ursprung'; 'Nachricht'};
            app.Log_UITable.RowName = {};
            app.angezeigteLogsSpinnerLabel = uilabel(app.LoggingPanel);
            app.angezeigteLogsSpinnerLabel.Text = '# angezeigte Logs ';
            app.AngezeigteLogs_Spinner = uispinner(app.LoggingPanel);
            app.AngezeigteLogs_Spinner.Step = 10;
            app.AngezeigteLogs_Spinner.Limits = [1 Inf];
            app.AngezeigteLogs_Spinner.RoundFractionalValues = 'on';
            app.AngezeigteLogs_Spinner.ValueChangedFcn = createCallbackFcn(app, @AngezeigteLogs_SpinnerValueChanged, true);
            app.AngezeigteLogs_Spinner.Value = 100;
            app.FilterEditFieldLabel = uilabel(app.LoggingPanel);
            app.FilterEditFieldLabel.Text = 'Filter';
            app.LogFilter_EditField = uieditfield(app.LoggingPanel, 'text');
            app.LogFilter_EditField.ValueChangedFcn = createCallbackFcn(app, @LogFilter_EditFieldValueChanged, true);
            app.SteuerungPanel = uipanel(app.PMVAltpapierKlassifiziererUIFigure);
            app.SteuerungPanel.Title = 'Steuerung';
            app.Start_Button = uibutton(app.SteuerungPanel, 'push');
            app.Start_Button.ButtonPushedFcn = createCallbackFcn(app, @Start_ButtonPushed, true);
            app.Start_Button.Enable = 'off';
            app.Start_Button.Text = 'Start';
            app.Stop_Button = uibutton(app.SteuerungPanel, 'push');
            app.Stop_Button.ButtonPushedFcn = createCallbackFcn(app, @Stop_ButtonPushed, true);
            app.Stop_Button.Enable = 'off';
            app.Stop_Button.Text = 'Stop';
            app.ProbenLabel = uilabel(app.SteuerungPanel);
            app.ProbenLabel.Enable = 'off';
            app.ProbenLabel.Text = 'Proben';
            app.Samples_Spinner = uispinner(app.SteuerungPanel);
            app.Samples_Spinner.Limits = [1 Inf];
            app.Samples_Spinner.RoundFractionalValues = 'on';
            app.Samples_Spinner.ValueDisplayFormat = '%.0f';
            app.Samples_Spinner.Enable = 'off';
            app.Samples_Spinner.Value = 1;
            app.ContinuousOperation_CheckBox = uicheckbox(app.SteuerungPanel);
            app.ContinuousOperation_CheckBox.ValueChangedFcn = createCallbackFcn(app, @ContinuousOperation_CheckBoxValueChanged, true);
            app.ContinuousOperation_CheckBox.Text = 'Dauerlauf';
            app.ContinuousOperation_CheckBox.Value = true;
            app.Process_EditField = uieditfield(app.SteuerungPanel, 'text');
            app.ProzessLabel = uilabel(app.SteuerungPanel);
            app.ProzessLabel.Text = 'Prozess';
            app.Process_Lamp = uilamp(app.SteuerungPanel);
            app.Init_Button = uibutton(app.SteuerungPanel, 'push');
            app.Init_Button.ButtonPushedFcn = createCallbackFcn(app, @Init_ButtonPushed, true);
            app.Init_Button.Text = 'Init';
            app.Image = uiimage(app.SteuerungPanel);
            app.Image.ImageSource = 'pmv_logo.png';
            app.RemainingSamplesSlash_Label = uilabel(app.SteuerungPanel);
            app.RemainingSamplesSlash_Label.Text = '/';
            app.RemainingSamples_EditField = uieditfield(app.SteuerungPanel, 'numeric');
            app.RemainingSamples_EditField.Limits = [0 Inf];
            app.RemainingSamples_EditField.Editable = 'off';
            app.RemainingSamples_EditField.Enable = 'off';
            % Show the figure after all components are created
            app.PMVAltpapierKlassifiziererUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProcessGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PMVAltpapierKlassifiziererUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PMVAltpapierKlassifiziererUIFigure)
        end
    end
end