classdef ProcessGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        OptionenMenu                   matlab.ui.container.Menu
        EinstellungenMenu              matlab.ui.container.Menu
        ArbeitsverzeichnissetzenMenu   matlab.ui.container.Menu
        KamerasMenu                    matlab.ui.container.Menu
        Kamera1anzeigenMenu            matlab.ui.container.Menu
        Kamera2anzeigenMenu            matlab.ui.container.Menu
        HilfeMenu_2                    matlab.ui.container.Menu
        HilfeMenu_3                    matlab.ui.container.Menu
        berMenu                        matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        GesamtprozessTab               matlab.ui.container.Tab
        IsolierungPanel                matlab.ui.container.Panel
        TrommelLampLabel               matlab.ui.control.Label
        DrumFeeder_Lamp                matlab.ui.control.Lamp
        ConvBelt_EditField             matlab.ui.control.EditField
        FrderbandLamp_3Label           matlab.ui.control.Label
        ConvBelt_Lamp                  matlab.ui.control.Lamp
        Robot_EditField                matlab.ui.control.EditField
        RoboterLampLabel               matlab.ui.control.Label
        Robot_Lamp                     matlab.ui.control.Lamp
        DrumFeeder_EditField           matlab.ui.control.EditField
        Gripper_EditField              matlab.ui.control.EditField
        GreiferLampLabel               matlab.ui.control.Label
        Gripper_Lamp                   matlab.ui.control.Lamp
        ObjectDetection_EditField      matlab.ui.control.EditField
        ObjekterkennungLamp_2Label     matlab.ui.control.Label
        ObjectDetection_Lamp           matlab.ui.control.Lamp
        MesssystemPanel                matlab.ui.container.Panel
        WeighingBelt_EditField         matlab.ui.control.EditField
        FrderbandLamp_4Label           matlab.ui.control.Label
        WeighingBelt_Lamp              matlab.ui.control.Lamp
        Camera_EditField               matlab.ui.control.EditField
        Scale_EditField                matlab.ui.control.EditField
        KameraLampLabel                matlab.ui.control.Label
        Camera_Lamp                    matlab.ui.control.Lamp
        WaageLampLabel                 matlab.ui.control.Label
        Scale_Lamp                     matlab.ui.control.Lamp
        Light_EditField                matlab.ui.control.EditField
        LichtLabel                     matlab.ui.control.Label
        Light_Lamp                     matlab.ui.control.Lamp
        MeasuringSystem_EditField      matlab.ui.control.EditField
        MesssystemLabel                matlab.ui.control.Label
        MeasuringSystem_Lamp           matlab.ui.control.Lamp
        IsolationDevice_EditField      matlab.ui.control.EditField
        IsolierungLabel                matlab.ui.control.Label
        IsolationDevice_Lamp           matlab.ui.control.Lamp
        MesssystemTab                  matlab.ui.container.Tab
        KameraPanel                    matlab.ui.container.Panel
        IsolationDevice_Tab            matlab.ui.container.Tab
        IsolationDevice_TabGroup       matlab.ui.container.TabGroup
        RoboterGreiferTab              matlab.ui.container.Tab
        ZustandEditFieldLabel          matlab.ui.control.Label
        ZustandEditField               matlab.ui.control.EditField
        PosePanel                      matlab.ui.container.Panel
        xEditFieldLabel                matlab.ui.control.Label
        xEditField                     matlab.ui.control.NumericEditField
        yEditFieldLabel                matlab.ui.control.Label
        yEditField                     matlab.ui.control.NumericEditField
        zEditFieldLabel                matlab.ui.control.Label
        zEditField                     matlab.ui.control.NumericEditField
        ryEditFieldLabel               matlab.ui.control.Label
        ryEditField                    matlab.ui.control.NumericEditField
        rzEditFieldLabel               matlab.ui.control.Label
        rzEditField                    matlab.ui.control.NumericEditField
        rxEditFieldLabel               matlab.ui.control.Label
        rxEditField                    matlab.ui.control.NumericEditField
        SteuerungCheckBox              matlab.ui.control.CheckBox
        BewegenButton                  matlab.ui.control.Button
        SollLabel                      matlab.ui.control.Label
        IstLabel                       matlab.ui.control.Label
        xEditField_2Label              matlab.ui.control.Label
        xEditField_2                   matlab.ui.control.NumericEditField
        yEditField_2Label              matlab.ui.control.Label
        yEditField_2                   matlab.ui.control.NumericEditField
        zEditField_2Label              matlab.ui.control.Label
        zEditField_2                   matlab.ui.control.NumericEditField
        ryEditField_2Label             matlab.ui.control.Label
        ryEditField_2                  matlab.ui.control.NumericEditField
        rzEditField_2Label             matlab.ui.control.Label
        rzEditField_2                  matlab.ui.control.NumericEditField
        rxEditField_2Label             matlab.ui.control.Label
        rxEditField_2                  matlab.ui.control.NumericEditField
        GreiferPanel                   matlab.ui.container.Panel
        ZangeLinksGaugeLabel           matlab.ui.control.Label
        ZangeLinksGauge                matlab.ui.control.NinetyDegreeGauge
        ZangeRechtsGaugeLabel          matlab.ui.control.Label
        ZangeRechtsGauge               matlab.ui.control.NinetyDegreeGauge
        KontaktLampLabel               matlab.ui.control.Label
        KontaktLamp                    matlab.ui.control.Lamp
        OffsetEditFieldLabel           matlab.ui.control.Label
        OffsetEditField                matlab.ui.control.NumericEditField
        OffsetEditField_2              matlab.ui.control.NumericEditField
        OffsetEditField_3              matlab.ui.control.NumericEditField
        OpenCloseGripper_Button        matlab.ui.control.StateButton
        UIAxes                         matlab.ui.control.UIAxes
        UnterdruckpumpePanel           matlab.ui.container.Panel
        SignalLampLabel                matlab.ui.control.Label
        SignalLamp                     matlab.ui.control.Lamp
        DruckGaugeLabel                matlab.ui.control.Label
        DruckGauge                     matlab.ui.control.LinearGauge
        AnAusButton                    matlab.ui.control.Button
        FrderbandPanel                 matlab.ui.container.Panel
        LichtschrankeLampLabel         matlab.ui.control.Label
        LichtschrankeLamp              matlab.ui.control.Lamp
        SliderLabel                    matlab.ui.control.Label
        Slider                         matlab.ui.control.Slider
        AnAusButton_2                  matlab.ui.control.StateButton
        ObjekterkennungTab             matlab.ui.container.Tab
        FrderbandTab                   matlab.ui.container.Tab
        TrommelTab                     matlab.ui.container.Tab
        LoggingPanel                   matlab.ui.container.Panel
        FehlerCheckBox                 matlab.ui.control.CheckBox
        WarnungCheckBox                matlab.ui.control.CheckBox
        InfoCheckBox                   matlab.ui.control.CheckBox
        DebugCheckBox                  matlab.ui.control.CheckBox
        Log_UITable                    matlab.ui.control.Table
        SteuerungPanel                 matlab.ui.container.Panel
        Start_Button                   matlab.ui.control.Button
        Stop_Button                    matlab.ui.control.Button
        Pause_Button                   matlab.ui.control.Button
        AusstehendeProbenSpinnerLabel  matlab.ui.control.Label
        RemainingSamples_Spinner       matlab.ui.control.Spinner
        ContinuousOperation_CheckBox   matlab.ui.control.CheckBox
        Process_EditField              matlab.ui.control.EditField
        ProzessLabel                   matlab.ui.control.Label
        Process_Lamp                   matlab.ui.control.Lamp
    end

    
    properties (Access = public)
        logger % Description
        allowed_log_levels = [1,1,1,1]; % Description
    end
    
    properties (Access = private)
        process % Description
        update_timer % Description
        lamp_colors % Description
        process_init_complete = false; % Description
    end

    methods (Access = public)
    
        function updateStates(app, ~, ~)
            app.updateState(app.Process_Lamp,app.Process_EditField, app.process);
            % ISOLATION DEVICE
            app.updateState(app.IsolationDevice_Lamp,app.IsolationDevice_EditField, app.process.isoDevice);
            app.updateState(app.Robot_Lamp,app.Robot_EditField, app.process.isoDevice.robot);
            app.updateState(app.ObjectDetection_Lamp,app.ObjectDetection_EditField, app.process.isoDevice.robot.objDetection);
            app.updateState(app.Gripper_Lamp,app.Gripper_EditField, app.process.isoDevice.robot.gripper);
            app.updateState(app.DrumFeeder_Lamp,app.DrumFeeder_EditField, app.process.isoDevice.drumFeeder);
            app.updateState(app.ConvBelt_Lamp,app.ConvBelt_EditField, app.process.isoDevice.convBelt);
            %MEASURING SYSTEM
            app.updateState(app.MeasuringSystem_Lamp,app.MeasuringSystem_EditField, app.process.measSystem);
            app.updateState(app.WeighingBelt_Lamp,app.WeighingBelt_EditField, app.process.measSystem.weighingBelt);
            app.updateState(app.Camera_Lamp,app.Camera_EditField, app.process.measSystem.cam);
            app.updateState(app.Light_Lamp,app.Light_EditField, app.process.measSystem.light);
            app.updateState(app.Scale_Lamp,app.Scale_EditField, app.process.measSystem.scale);
        end
        
        function updateState(app, lamp, editfield, state_object)
            state_object.updateState();
            state = state_object.getState();
            state_description = state_object.getStateDescription();
            lamp.Color = app.lamp_colors.(state_object.STATE_STRINGS{state});
            editfield.Value = state_description;
        end
        
        function logToTable(app, log_entry)
            if app.allowed_log_levels(log_entry.level)
                app.Log_UITable.Data = [...
                    {app.logger.formatTime(log_entry),...
                    app.logger.formatLevel(log_entry),...
                    app.logger.formatSource(log_entry),...
                    app.logger.formatMessage(log_entry)};...
                    app.Log_UITable.Data];
            end
        end
    
        function refreshLogTable(app)
            app.Log_UITable.Data = [];
            for i = 1:length(app.logger.log_store)
                if app.allowed_log_levels(app.logger.log_store(i).level)
                    app.logToTable(app.logger.log_store(i))
                end
            end
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, process, logger)
            app.lamp_colors.OFFLINE = [0.65,0.65,0.65];
            app.lamp_colors.ONLINE = [0.07,0.62,1];
            app.lamp_colors.ACTIVE = [0,1,0];
            app.lamp_colors.UNKNOWN = [1,1,0];
            app.lamp_colors.ERROR = [1,0,0];            
            
            if nargin < 2
                app.process = [];
            else
                app.process = process;
            end
            if nargin < 3
                app.logger = [];
            else
                logger.log_remote_fcn_handle = @app.logToTable;
                logger.log_remote_fcn_active = true;
                app.logger = logger;
                app.refreshLogTable();
            end
            
            app.update_timer = timer('Period',1,'BusyMode','drop','ExecutionMode','fixedRate');
            app.update_timer.TimerFcn = @app.updateStates;
            app.update_timer.start();
        end

        % Menu selected function: HilfeMenu_3
        function HilfeMenuSelected(app, event)
            HelpGUI()
        end

        % Value changed function: DebugCheckBox
        function DebugCheckBoxValueChanged(app, event)
            app.allowed_log_levels(1) = app.DebugCheckBox.Value;
            app.refreshLogTable();
        end

        % Value changed function: InfoCheckBox
        function InfoCheckBoxValueChanged(app, event)
            app.allowed_log_levels(2) = app.InfoCheckBox.Value;
            app.refreshLogTable();
        end

        % Value changed function: WarnungCheckBox
        function WarnungCheckBoxValueChanged(app, event)
            app.allowed_log_levels(3) = app.WarnungCheckBox.Value;
            app.refreshLogTable();
        end

        % Value changed function: FehlerCheckBox
        function FehlerCheckBoxValueChanged(app, event)
            app.allowed_log_levels(4) = app.FehlerCheckBox.Value;
            app.refreshLogTable();
        end

        % Menu selected function: EinstellungenMenu
        function EinstellungenMenuSelected(app, event)
            SettingsGUI(app) 
        end

        % Button pushed function: Start_Button
        function Start_ButtonPushed(app, event)
            if app.process_init_complete
                if app.ContinuousOperation_CheckBox.Value
                    app.process.run(intmax);
                else
                    app.process.run(app.RemainingSamples_Spinner.Value);
                end
            else
                app.Start_Button.Enable = false;
                app.process.init();
                app.Start_Button.Enable = true;
                app.Start_Button.Text = 'Start';
                app.process_init_complete = true;
            end
        end

        % Value changed function: ContinuousOperation_CheckBox
        function ContinuousOperation_CheckBoxValueChanged(app, event)
            app.RemainingSamples_Spinner.Enable = ~app.ContinuousOperation_CheckBox.Value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.update_timer.stop();
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OptionenMenu = uimenu(app.UIFigure);
            app.OptionenMenu.Text = 'Optionen';
            app.EinstellungenMenu = uimenu(app.OptionenMenu);
            app.EinstellungenMenu.MenuSelectedFcn = createCallbackFcn(app, @EinstellungenMenuSelected, true);
            app.EinstellungenMenu.Text = 'Einstellungen...';
            app.ArbeitsverzeichnissetzenMenu = uimenu(app.OptionenMenu);
            app.ArbeitsverzeichnissetzenMenu.Text = 'Arbeitsverzeichnis setzen';
            app.KamerasMenu = uimenu(app.OptionenMenu);
            app.KamerasMenu.Text = 'Kameras ...';
            app.Kamera1anzeigenMenu = uimenu(app.KamerasMenu);
            app.Kamera1anzeigenMenu.Text = 'Kamera 1 anzeigen';
            app.Kamera2anzeigenMenu = uimenu(app.KamerasMenu);
            app.Kamera2anzeigenMenu.Text = 'Kamera 2 anzeigen';
            app.HilfeMenu_2 = uimenu(app.UIFigure);
            app.HilfeMenu_2.Text = 'Hilfe';
            app.HilfeMenu_3 = uimenu(app.HilfeMenu_2);
            app.HilfeMenu_3.MenuSelectedFcn = createCallbackFcn(app, @HilfeMenuSelected, true);
            app.HilfeMenu_3.Text = 'Hilfe';
            app.berMenu = uimenu(app.HilfeMenu_2);
            app.berMenu.Text = 'Über';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GesamtprozessTab = uitab(app.TabGroup);
            app.GesamtprozessTab.Title = 'Gesamtprozess';
            app.IsolierungPanel = uipanel(app.GesamtprozessTab);
            app.IsolierungPanel.Title = 'Isolierung';
            app.TrommelLampLabel = uilabel(app.IsolierungPanel);
            app.TrommelLampLabel.Text = 'Trommel';
            app.DrumFeeder_Lamp = uilamp(app.IsolierungPanel);
            app.ConvBelt_EditField = uieditfield(app.IsolierungPanel, 'text');
            app.FrderbandLamp_3Label = uilabel(app.IsolierungPanel);
            app.FrderbandLamp_3Label.Text = 'Förderband';
            app.ConvBelt_Lamp = uilamp(app.IsolierungPanel);
            app.Robot_EditField = uieditfield(app.IsolierungPanel, 'text');
            app.RoboterLampLabel = uilabel(app.IsolierungPanel);
            app.RoboterLampLabel.Text = 'Roboter';
            app.Robot_Lamp = uilamp(app.IsolierungPanel);
            app.DrumFeeder_EditField = uieditfield(app.IsolierungPanel, 'text');
            app.Gripper_EditField = uieditfield(app.IsolierungPanel, 'text');
            app.GreiferLampLabel = uilabel(app.IsolierungPanel);
            app.GreiferLampLabel.Text = 'Greifer';
            app.Gripper_Lamp = uilamp(app.IsolierungPanel);
            app.ObjectDetection_EditField = uieditfield(app.IsolierungPanel, 'text');
            app.ObjekterkennungLamp_2Label = uilabel(app.IsolierungPanel);
            app.ObjekterkennungLamp_2Label.Text = 'Objekterkennung';
            app.ObjectDetection_Lamp = uilamp(app.IsolierungPanel);
            app.MesssystemPanel = uipanel(app.GesamtprozessTab);
            app.MesssystemPanel.Title = 'Messsystem';
            app.WeighingBelt_EditField = uieditfield(app.MesssystemPanel, 'text');
            app.FrderbandLamp_4Label = uilabel(app.MesssystemPanel);
            app.FrderbandLamp_4Label.Text = 'Förderband';
            app.WeighingBelt_Lamp = uilamp(app.MesssystemPanel);
            app.Camera_EditField = uieditfield(app.MesssystemPanel, 'text');
            app.Scale_EditField = uieditfield(app.MesssystemPanel, 'text');
            app.KameraLampLabel = uilabel(app.MesssystemPanel);
            app.KameraLampLabel.Text = 'Kamera';
            app.Camera_Lamp = uilamp(app.MesssystemPanel);
            app.WaageLampLabel = uilabel(app.MesssystemPanel);
            app.WaageLampLabel.Text = 'Waage';
            app.Scale_Lamp = uilamp(app.MesssystemPanel);
            app.Light_EditField = uieditfield(app.MesssystemPanel, 'text');
            app.LichtLabel = uilabel(app.MesssystemPanel);
            app.LichtLabel.Text = 'Licht';
            app.Light_Lamp = uilamp(app.MesssystemPanel);
            app.MeasuringSystem_EditField = uieditfield(app.GesamtprozessTab, 'text');
            app.MesssystemLabel = uilabel(app.GesamtprozessTab);
            app.MesssystemLabel.Text = 'Messsystem';
            app.MeasuringSystem_Lamp = uilamp(app.GesamtprozessTab);
            app.IsolationDevice_EditField = uieditfield(app.GesamtprozessTab, 'text');
            app.IsolierungLabel = uilabel(app.GesamtprozessTab);
            app.IsolierungLabel.Text = 'Isolierung';
            app.IsolationDevice_Lamp = uilamp(app.GesamtprozessTab);
            app.MesssystemTab = uitab(app.TabGroup);
            app.MesssystemTab.Title = 'Messsystem';
            app.KameraPanel = uipanel(app.MesssystemTab);
            app.KameraPanel.Title = 'Kamera';
            app.IsolationDevice_Tab = uitab(app.TabGroup);
            app.IsolationDevice_Tab.Title = 'Isolierung';
            app.IsolationDevice_TabGroup = uitabgroup(app.IsolationDevice_Tab);
            app.RoboterGreiferTab = uitab(app.IsolationDevice_TabGroup);
            app.RoboterGreiferTab.Title = 'Roboter/Greifer';
            app.ZustandEditFieldLabel = uilabel(app.RoboterGreiferTab);
            app.ZustandEditFieldLabel.Text = 'Zustand';
            app.ZustandEditField = uieditfield(app.RoboterGreiferTab, 'text');
            app.PosePanel = uipanel(app.RoboterGreiferTab);
            app.PosePanel.Title = 'Pose';
            app.xEditFieldLabel = uilabel(app.PosePanel);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.PosePanel, 'numeric');
            app.xEditField.Enable = 'off';
            app.yEditFieldLabel = uilabel(app.PosePanel);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.PosePanel, 'numeric');
            app.yEditField.Enable = 'off';
            app.zEditFieldLabel = uilabel(app.PosePanel);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.PosePanel, 'numeric');
            app.zEditField.Enable = 'off';
            app.ryEditFieldLabel = uilabel(app.PosePanel);
            app.ryEditFieldLabel.Text = 'ry';
            app.ryEditField = uieditfield(app.PosePanel, 'numeric');
            app.ryEditField.Enable = 'off';
            app.rzEditFieldLabel = uilabel(app.PosePanel);
            app.rzEditFieldLabel.Text = 'rz';
            app.rzEditField = uieditfield(app.PosePanel, 'numeric');
            app.rzEditField.Enable = 'off';
            app.rxEditFieldLabel = uilabel(app.PosePanel);
            app.rxEditFieldLabel.Text = 'rx';
            app.rxEditField = uieditfield(app.PosePanel, 'numeric');
            app.rxEditField.Enable = 'off';
            app.SteuerungCheckBox = uicheckbox(app.PosePanel);
            app.SteuerungCheckBox.Text = 'Steuerung';
            app.BewegenButton = uibutton(app.PosePanel, 'push');
            app.BewegenButton.Text = 'Bewegen';
            app.SollLabel = uilabel(app.PosePanel);
            app.SollLabel.Text = 'Soll';
            app.IstLabel = uilabel(app.PosePanel);
            app.IstLabel.Text = 'Ist';
            app.xEditField_2Label = uilabel(app.PosePanel);
            app.xEditField_2Label.Text = 'x';
            app.xEditField_2 = uieditfield(app.PosePanel, 'numeric');
            app.xEditField_2.Editable = 'off';
            app.yEditField_2Label = uilabel(app.PosePanel);
            app.yEditField_2Label.Text = 'y';
            app.yEditField_2 = uieditfield(app.PosePanel, 'numeric');
            app.yEditField_2.Editable = 'off';
            app.zEditField_2Label = uilabel(app.PosePanel);
            app.zEditField_2Label.Text = 'z';
            app.zEditField_2 = uieditfield(app.PosePanel, 'numeric');
            app.zEditField_2.Editable = 'off';
            app.ryEditField_2Label = uilabel(app.PosePanel);
            app.ryEditField_2Label.Text = 'ry';
            app.ryEditField_2 = uieditfield(app.PosePanel, 'numeric');
            app.ryEditField_2.Editable = 'off';
            app.rzEditField_2Label = uilabel(app.PosePanel);
            app.rzEditField_2Label.Text = 'rz';
            app.rzEditField_2 = uieditfield(app.PosePanel, 'numeric');
            app.rzEditField_2.Editable = 'off';
            app.rxEditField_2Label = uilabel(app.PosePanel);
            app.rxEditField_2Label.Text = 'rx';
            app.rxEditField_2 = uieditfield(app.PosePanel, 'numeric');
            app.rxEditField_2.Editable = 'off';
            app.GreiferPanel = uipanel(app.RoboterGreiferTab);
            app.GreiferPanel.Title = 'Greifer';
            app.ZangeLinksGaugeLabel = uilabel(app.GreiferPanel);
            app.ZangeLinksGaugeLabel.Text = 'Zange Links';
            app.ZangeLinksGauge = uigauge(app.GreiferPanel, 'ninetydegree');
            app.ZangeLinksGauge.MajorTicks = [0 30 60 90];
            app.ZangeLinksGauge.ScaleDirection = 'counterclockwise';
            app.ZangeRechtsGaugeLabel = uilabel(app.GreiferPanel);
            app.ZangeRechtsGaugeLabel.Text = 'Zange Rechts';
            app.ZangeRechtsGauge = uigauge(app.GreiferPanel, 'ninetydegree');
            app.ZangeRechtsGauge.MajorTicks = [0 30 60 90];
            app.KontaktLampLabel = uilabel(app.GreiferPanel);
            app.KontaktLampLabel.Text = 'Kontakt';
            app.KontaktLamp = uilamp(app.GreiferPanel);
            app.OffsetEditFieldLabel = uilabel(app.GreiferPanel);
            app.OffsetEditFieldLabel.Text = 'Offset';
            app.OffsetEditField = uieditfield(app.GreiferPanel, 'numeric');
            app.OffsetEditField_2 = uieditfield(app.GreiferPanel, 'numeric');
            app.OffsetEditField_3 = uieditfield(app.GreiferPanel, 'numeric');
            app.OpenCloseGripper_Button = uibutton(app.GreiferPanel, 'state');
            app.OpenCloseGripper_Button.Text = 'Öffnen/Schließen';
            app.UIAxes = uiaxes(app.RoboterGreiferTab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UnterdruckpumpePanel = uipanel(app.RoboterGreiferTab);
            app.UnterdruckpumpePanel.Title = 'Unterdruckpumpe';
            app.SignalLampLabel = uilabel(app.UnterdruckpumpePanel);
            app.SignalLampLabel.Text = 'Signal';
            app.SignalLamp = uilamp(app.UnterdruckpumpePanel);
            app.DruckGaugeLabel = uilabel(app.UnterdruckpumpePanel);
            app.DruckGaugeLabel.Text = 'Druck';
            app.DruckGauge = uigauge(app.UnterdruckpumpePanel, 'linear');
            app.AnAusButton = uibutton(app.UnterdruckpumpePanel, 'push');
            app.AnAusButton.Text = 'An/Aus';
            app.FrderbandPanel = uipanel(app.RoboterGreiferTab);
            app.FrderbandPanel.Title = 'Förderband';
            app.LichtschrankeLampLabel = uilabel(app.FrderbandPanel);
            app.LichtschrankeLampLabel.Text = 'Lichtschranke';
            app.LichtschrankeLamp = uilamp(app.FrderbandPanel);
            app.SliderLabel = uilabel(app.FrderbandPanel);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.FrderbandPanel);
            app.AnAusButton_2 = uibutton(app.FrderbandPanel, 'state');
            app.AnAusButton_2.Text = 'An/Aus';
            app.ObjekterkennungTab = uitab(app.IsolationDevice_TabGroup);
            app.ObjekterkennungTab.Title = 'Objekterkennung';
            app.FrderbandTab = uitab(app.IsolationDevice_TabGroup);
            app.FrderbandTab.Title = 'Förderband';
            app.TrommelTab = uitab(app.IsolationDevice_TabGroup);
            app.TrommelTab.Title = 'Trommel';
            app.LoggingPanel = uipanel(app.UIFigure);
            app.LoggingPanel.Title = 'Logging';
            app.FehlerCheckBox = uicheckbox(app.LoggingPanel);
            app.FehlerCheckBox.ValueChangedFcn = createCallbackFcn(app, @FehlerCheckBoxValueChanged, true);
            app.FehlerCheckBox.Text = 'Fehler';
            app.FehlerCheckBox.Value = true;
            app.WarnungCheckBox = uicheckbox(app.LoggingPanel);
            app.WarnungCheckBox.ValueChangedFcn = createCallbackFcn(app, @WarnungCheckBoxValueChanged, true);
            app.WarnungCheckBox.Text = 'Warnung';
            app.WarnungCheckBox.Value = true;
            app.InfoCheckBox = uicheckbox(app.LoggingPanel);
            app.InfoCheckBox.ValueChangedFcn = createCallbackFcn(app, @InfoCheckBoxValueChanged, true);
            app.InfoCheckBox.Text = 'Info';
            app.InfoCheckBox.Value = true;
            app.DebugCheckBox = uicheckbox(app.LoggingPanel);
            app.DebugCheckBox.ValueChangedFcn = createCallbackFcn(app, @DebugCheckBoxValueChanged, true);
            app.DebugCheckBox.Text = 'Debug';
            app.DebugCheckBox.Value = true;
            app.Log_UITable = uitable(app.LoggingPanel);
            app.Log_UITable.ColumnName = {'Zeit'; 'Level'; 'Ursprung'; 'Nachricht'};
            app.Log_UITable.RowName = {};
            app.SteuerungPanel = uipanel(app.UIFigure);
            app.SteuerungPanel.Title = 'Steuerung';
            app.Start_Button = uibutton(app.SteuerungPanel, 'push');
            app.Start_Button.ButtonPushedFcn = createCallbackFcn(app, @Start_ButtonPushed, true);
            app.Start_Button.Text = 'Init';
            app.Stop_Button = uibutton(app.SteuerungPanel, 'push');
            app.Stop_Button.Text = 'Stop';
            app.Pause_Button = uibutton(app.SteuerungPanel, 'push');
            app.Pause_Button.Text = 'Pause';
            app.AusstehendeProbenSpinnerLabel = uilabel(app.SteuerungPanel);
            app.AusstehendeProbenSpinnerLabel.Enable = 'off';
            app.AusstehendeProbenSpinnerLabel.Text = 'Ausstehende Proben';
            app.RemainingSamples_Spinner = uispinner(app.SteuerungPanel);
            app.RemainingSamples_Spinner.Limits = [0 Inf];
            app.RemainingSamples_Spinner.RoundFractionalValues = 'on';
            app.RemainingSamples_Spinner.ValueDisplayFormat = '%.0f';
            app.RemainingSamples_Spinner.Enable = 'off';
            app.ContinuousOperation_CheckBox = uicheckbox(app.SteuerungPanel);
            app.ContinuousOperation_CheckBox.ValueChangedFcn = createCallbackFcn(app, @ContinuousOperation_CheckBoxValueChanged, true);
            app.ContinuousOperation_CheckBox.Text = 'Dauerlauf';
            app.ContinuousOperation_CheckBox.Value = true;
            app.Process_EditField = uieditfield(app.SteuerungPanel, 'text');
            app.ProzessLabel = uilabel(app.SteuerungPanel);
            app.ProzessLabel.Text = 'Prozess';
            app.Process_Lamp = uilamp(app.SteuerungPanel);
        end
    end

    methods (Access = public)

        % Construct app
        function app = ProcessGUI(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end