classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        OptionenMenu                   matlab.ui.container.Menu
        ArbeitsverzeichnissetzenMenu   matlab.ui.container.Menu
        KamerasMenu                    matlab.ui.container.Menu
        Kamera1anzeigenMenu            matlab.ui.container.Menu
        Kamera2anzeigenMenu            matlab.ui.container.Menu
        HilfeMenu                      matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        GesamtprozessTab               matlab.ui.container.Tab
        IsolierungPanel                matlab.ui.container.Panel
        TrommelLampLabel               matlab.ui.control.Label
        TrommelLamp                    matlab.ui.control.Lamp
        Label                          matlab.ui.control.Label
        EditField                      matlab.ui.control.EditField
        EditField_2                    matlab.ui.control.EditField
        FrderbandLamp_3Label           matlab.ui.control.Label
        FrderbandLamp_3                matlab.ui.control.Lamp
        EditField_3                    matlab.ui.control.EditField
        RoboterLampLabel               matlab.ui.control.Label
        RoboterLamp                    matlab.ui.control.Lamp
        MesssystemPanel                matlab.ui.container.Panel
        EditField_4                    matlab.ui.control.EditField
        ZufhrungLampLabel              matlab.ui.control.Label
        ZufhrungLamp                   matlab.ui.control.Lamp
        EditField_5                    matlab.ui.control.EditField
        RoboterLamp_2Label             matlab.ui.control.Label
        RoboterLamp_2                  matlab.ui.control.Lamp
        EditField_6                    matlab.ui.control.EditField
        FrderbandLamp_4Label           matlab.ui.control.Label
        FrderbandLamp_4                matlab.ui.control.Lamp
        EditField_7                    matlab.ui.control.EditField
        EditField_8                    matlab.ui.control.EditField
        KameraLampLabel                matlab.ui.control.Label
        KameraLamp                     matlab.ui.control.Lamp
        WaageLampLabel                 matlab.ui.control.Label
        WaageLamp                      matlab.ui.control.Lamp
        SteuerungPanel                 matlab.ui.container.Panel
        StartButton                    matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
        PauseButton                    matlab.ui.control.Button
        AusstehendeProbenSpinnerLabel  matlab.ui.control.Label
        AusstehendeProbenSpinner       matlab.ui.control.Spinner
        DauerlaufCheckBox              matlab.ui.control.CheckBox
        IsolierungTab                  matlab.ui.container.Tab
        TabGroup2                      matlab.ui.container.TabGroup
        VorvereinzelungTab             matlab.ui.container.Tab
        FrderbandTab                   matlab.ui.container.Tab
        RoboterTab                     matlab.ui.container.Tab
        ZustandEditFieldLabel          matlab.ui.control.Label
        ZustandEditField               matlab.ui.control.EditField
        PosePanel                      matlab.ui.container.Panel
        xEditFieldLabel                matlab.ui.control.Label
        xEditField                     matlab.ui.control.NumericEditField
        yEditFieldLabel                matlab.ui.control.Label
        yEditField                     matlab.ui.control.NumericEditField
        zEditFieldLabel                matlab.ui.control.Label
        zEditField                     matlab.ui.control.NumericEditField
        ryEditFieldLabel               matlab.ui.control.Label
        ryEditField                    matlab.ui.control.NumericEditField
        rzEditFieldLabel               matlab.ui.control.Label
        rzEditField                    matlab.ui.control.NumericEditField
        rxEditFieldLabel               matlab.ui.control.Label
        rxEditField                    matlab.ui.control.NumericEditField
        SteuerungCheckBox              matlab.ui.control.CheckBox
        BewegenButton                  matlab.ui.control.Button
        SollLabel                      matlab.ui.control.Label
        IstLabel                       matlab.ui.control.Label
        xEditField_2Label              matlab.ui.control.Label
        xEditField_2                   matlab.ui.control.NumericEditField
        yEditField_2Label              matlab.ui.control.Label
        yEditField_2                   matlab.ui.control.NumericEditField
        zEditField_2Label              matlab.ui.control.Label
        zEditField_2                   matlab.ui.control.NumericEditField
        ryEditField_2Label             matlab.ui.control.Label
        ryEditField_2                  matlab.ui.control.NumericEditField
        rzEditField_2Label             matlab.ui.control.Label
        rzEditField_2                  matlab.ui.control.NumericEditField
        rxEditField_2Label             matlab.ui.control.Label
        rxEditField_2                  matlab.ui.control.NumericEditField
        GripperPanel                   matlab.ui.container.Panel
        ZangeLinksGaugeLabel           matlab.ui.control.Label
        ZangeLinksGauge                matlab.ui.control.NinetyDegreeGauge
        ZangeRechtsGaugeLabel          matlab.ui.control.Label
        ZangeRechtsGauge               matlab.ui.control.NinetyDegreeGauge
        KontaktLampLabel               matlab.ui.control.Label
        KontaktLamp                    matlab.ui.control.Lamp
        OffsetEditFieldLabel           matlab.ui.control.Label
        OffsetEditField                matlab.ui.control.NumericEditField
        OffsetEditField_2              matlab.ui.control.NumericEditField
        OffsetEditField_3              matlab.ui.control.NumericEditField
        ffnenSchlieenButton            matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        UnterdruckpumpePanel           matlab.ui.container.Panel
        SignalLampLabel                matlab.ui.control.Label
        SignalLamp                     matlab.ui.control.Lamp
        DruckGaugeLabel                matlab.ui.control.Label
        DruckGauge                     matlab.ui.control.LinearGauge
        AnAusButton                    matlab.ui.control.Button
        FrderbandPanel                 matlab.ui.container.Panel
        LichtschrankeLampLabel         matlab.ui.control.Label
        LichtschrankeLamp              matlab.ui.control.Lamp
        SliderLabel                    matlab.ui.control.Label
        Slider                         matlab.ui.control.Slider
        AnAusButton_2                  matlab.ui.control.StateButton
        LichtschrankeLamp_2Label       matlab.ui.control.Label
        LichtschrankeLamp_2            matlab.ui.control.Lamp
        MesssystemTab                  matlab.ui.container.Tab
        LoggingPanel                   matlab.ui.container.Panel
        FehlerCheckBox                 matlab.ui.control.CheckBox
        WarnungCheckBox                matlab.ui.control.CheckBox
        InfoCheckBox                   matlab.ui.control.CheckBox
        DebugCheckBox                  matlab.ui.control.CheckBox
        UITable                        matlab.ui.control.Table
    end


    methods (Access = public)
    
        function results = func(app)
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.Data = [{datestr(now),'Debug','Some Function','Did something'};
                {datestr(now+1),'Error','Some Function','Did something'};
                {datestr(now+1),'Error','Some Function','Did something'};
                {datestr(now+1),'Error','Some Function','Did something'};
                {datestr(now+1),'Error','Some Function','Did something'};
                {datestr(now+1),'Error','Some Function','Did something'};
                {datestr(now+1),'Error','Some Function','Did something'};]
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.OptionenMenu = uimenu(app.UIFigure);
            app.OptionenMenu.Text = 'Optionen';
            app.ArbeitsverzeichnissetzenMenu = uimenu(app.OptionenMenu);
            app.ArbeitsverzeichnissetzenMenu.Text = 'Arbeitsverzeichnis setzen';
            app.KamerasMenu = uimenu(app.OptionenMenu);
            app.KamerasMenu.Text = 'Kameras ...';
            app.Kamera1anzeigenMenu = uimenu(app.KamerasMenu);
            app.Kamera1anzeigenMenu.Text = 'Kamera 1 anzeigen';
            app.Kamera2anzeigenMenu = uimenu(app.KamerasMenu);
            app.Kamera2anzeigenMenu.Text = 'Kamera 2 anzeigen';
            app.HilfeMenu = uimenu(app.OptionenMenu);
            app.HilfeMenu.Text = 'Hilfe';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GesamtprozessTab = uitab(app.TabGroup);
            app.GesamtprozessTab.Title = 'Gesamtprozess';
            app.IsolierungPanel = uipanel(app.GesamtprozessTab);
            app.IsolierungPanel.Title = 'Isolierung';
            app.TrommelLampLabel = uilabel(app.IsolierungPanel);
            app.TrommelLampLabel.Text = 'Trommel';
            app.TrommelLamp = uilamp(app.IsolierungPanel);
            app.Label = uilabel(app.IsolierungPanel);
            app.Label.Text = '';
            app.EditField = uieditfield(app.IsolierungPanel, 'text');
            app.EditField_2 = uieditfield(app.IsolierungPanel, 'text');
            app.FrderbandLamp_3Label = uilabel(app.IsolierungPanel);
            app.FrderbandLamp_3Label.Text = 'Förderband';
            app.FrderbandLamp_3 = uilamp(app.IsolierungPanel);
            app.EditField_3 = uieditfield(app.IsolierungPanel, 'text');
            app.RoboterLampLabel = uilabel(app.IsolierungPanel);
            app.RoboterLampLabel.Text = 'Roboter';
            app.RoboterLamp = uilamp(app.IsolierungPanel);
            app.MesssystemPanel = uipanel(app.GesamtprozessTab);
            app.MesssystemPanel.Title = 'Messsystem';
            app.EditField_4 = uieditfield(app.MesssystemPanel, 'text');
            app.ZufhrungLampLabel = uilabel(app.MesssystemPanel);
            app.ZufhrungLampLabel.Text = 'Zuführung';
            app.ZufhrungLamp = uilamp(app.MesssystemPanel);
            app.EditField_5 = uieditfield(app.MesssystemPanel, 'text');
            app.RoboterLamp_2Label = uilabel(app.MesssystemPanel);
            app.RoboterLamp_2Label.Text = 'Roboter';
            app.RoboterLamp_2 = uilamp(app.MesssystemPanel);
            app.EditField_6 = uieditfield(app.MesssystemPanel, 'text');
            app.FrderbandLamp_4Label = uilabel(app.MesssystemPanel);
            app.FrderbandLamp_4Label.Text = 'Förderband';
            app.FrderbandLamp_4 = uilamp(app.MesssystemPanel);
            app.EditField_7 = uieditfield(app.MesssystemPanel, 'text');
            app.EditField_8 = uieditfield(app.MesssystemPanel, 'text');
            app.KameraLampLabel = uilabel(app.MesssystemPanel);
            app.KameraLampLabel.Text = 'Kamera';
            app.KameraLamp = uilamp(app.MesssystemPanel);
            app.WaageLampLabel = uilabel(app.MesssystemPanel);
            app.WaageLampLabel.Text = 'Waage';
            app.WaageLamp = uilamp(app.MesssystemPanel);
            app.SteuerungPanel = uipanel(app.GesamtprozessTab);
            app.SteuerungPanel.Title = 'Steuerung';
            app.StartButton = uibutton(app.SteuerungPanel, 'push');
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.SteuerungPanel, 'push');
            app.StopButton.Text = 'Stop';
            app.PauseButton = uibutton(app.SteuerungPanel, 'push');
            app.PauseButton.Text = 'Pause';
            app.AusstehendeProbenSpinnerLabel = uilabel(app.SteuerungPanel);
            app.AusstehendeProbenSpinnerLabel.Enable = 'off';
            app.AusstehendeProbenSpinnerLabel.Text = 'Ausstehende Proben';
            app.AusstehendeProbenSpinner = uispinner(app.SteuerungPanel);
            app.AusstehendeProbenSpinner.Limits = [0 Inf];
            app.AusstehendeProbenSpinner.ValueDisplayFormat = '%.0f';
            app.AusstehendeProbenSpinner.Enable = 'off';
            app.DauerlaufCheckBox = uicheckbox(app.SteuerungPanel);
            app.DauerlaufCheckBox.Text = 'Dauerlauf';
            app.DauerlaufCheckBox.Value = true;
            app.IsolierungTab = uitab(app.TabGroup);
            app.IsolierungTab.Title = 'Isolierung';
            app.TabGroup2 = uitabgroup(app.IsolierungTab);
            app.VorvereinzelungTab = uitab(app.TabGroup2);
            app.VorvereinzelungTab.Title = 'Vorvereinzelung';
            app.FrderbandTab = uitab(app.TabGroup2);
            app.FrderbandTab.Title = 'Förderband';
            app.RoboterTab = uitab(app.TabGroup2);
            app.RoboterTab.Title = 'Roboter';
            app.ZustandEditFieldLabel = uilabel(app.RoboterTab);
            app.ZustandEditFieldLabel.Text = 'Zustand';
            app.ZustandEditField = uieditfield(app.RoboterTab, 'text');
            app.PosePanel = uipanel(app.RoboterTab);
            app.PosePanel.Title = 'Pose';
            app.xEditFieldLabel = uilabel(app.PosePanel);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.PosePanel, 'numeric');
            app.xEditField.Enable = 'off';
            app.yEditFieldLabel = uilabel(app.PosePanel);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.PosePanel, 'numeric');
            app.yEditField.Enable = 'off';
            app.zEditFieldLabel = uilabel(app.PosePanel);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.PosePanel, 'numeric');
            app.zEditField.Enable = 'off';
            app.ryEditFieldLabel = uilabel(app.PosePanel);
            app.ryEditFieldLabel.Text = 'ry';
            app.ryEditField = uieditfield(app.PosePanel, 'numeric');
            app.ryEditField.Enable = 'off';
            app.rzEditFieldLabel = uilabel(app.PosePanel);
            app.rzEditFieldLabel.Text = 'rz';
            app.rzEditField = uieditfield(app.PosePanel, 'numeric');
            app.rzEditField.Enable = 'off';
            app.rxEditFieldLabel = uilabel(app.PosePanel);
            app.rxEditFieldLabel.Text = 'rx';
            app.rxEditField = uieditfield(app.PosePanel, 'numeric');
            app.rxEditField.Enable = 'off';
            app.SteuerungCheckBox = uicheckbox(app.PosePanel);
            app.SteuerungCheckBox.Text = 'Steuerung';
            app.BewegenButton = uibutton(app.PosePanel, 'push');
            app.BewegenButton.Text = 'Bewegen';
            app.SollLabel = uilabel(app.PosePanel);
            app.SollLabel.Text = 'Soll';
            app.IstLabel = uilabel(app.PosePanel);
            app.IstLabel.Text = 'Ist';
            app.xEditField_2Label = uilabel(app.PosePanel);
            app.xEditField_2Label.Text = 'x';
            app.xEditField_2 = uieditfield(app.PosePanel, 'numeric');
            app.xEditField_2.Editable = 'off';
            app.yEditField_2Label = uilabel(app.PosePanel);
            app.yEditField_2Label.Text = 'y';
            app.yEditField_2 = uieditfield(app.PosePanel, 'numeric');
            app.yEditField_2.Editable = 'off';
            app.zEditField_2Label = uilabel(app.PosePanel);
            app.zEditField_2Label.Text = 'z';
            app.zEditField_2 = uieditfield(app.PosePanel, 'numeric');
            app.zEditField_2.Editable = 'off';
            app.ryEditField_2Label = uilabel(app.PosePanel);
            app.ryEditField_2Label.Text = 'ry';
            app.ryEditField_2 = uieditfield(app.PosePanel, 'numeric');
            app.ryEditField_2.Editable = 'off';
            app.rzEditField_2Label = uilabel(app.PosePanel);
            app.rzEditField_2Label.Text = 'rz';
            app.rzEditField_2 = uieditfield(app.PosePanel, 'numeric');
            app.rzEditField_2.Editable = 'off';
            app.rxEditField_2Label = uilabel(app.PosePanel);
            app.rxEditField_2Label.Text = 'rx';
            app.rxEditField_2 = uieditfield(app.PosePanel, 'numeric');
            app.rxEditField_2.Editable = 'off';
            app.FrderbandPanel = uipanel(app.RoboterTab);
            app.FrderbandPanel.Title = 'Förderband';
            app.LichtschrankeLampLabel = uilabel(app.FrderbandPanel);
            app.LichtschrankeLampLabel.Text = 'Lichtschranke';
            app.LichtschrankeLamp = uilamp(app.FrderbandPanel);
            app.SliderLabel = uilabel(app.FrderbandPanel);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.FrderbandPanel);
            app.AnAusButton_2 = uibutton(app.FrderbandPanel, 'state');
            app.AnAusButton_2.Text = 'An/Aus';
            app.LichtschrankeLamp_2Label = uilabel(app.FrderbandPanel);
            app.LichtschrankeLamp_2Label.Text = 'Lichtschranke';
            app.LichtschrankeLamp_2 = uilamp(app.FrderbandPanel);
            app.GripperPanel = uipanel(app.RoboterTab);
            app.GripperPanel.Title = 'Gripper';
            app.ZangeLinksGaugeLabel = uilabel(app.GripperPanel);
            app.ZangeLinksGaugeLabel.Text = 'Zange Links';
            app.ZangeLinksGauge = uigauge(app.GripperPanel, 'ninetydegree');
            app.ZangeLinksGauge.MajorTicks = [0 30 60 90];
            app.ZangeLinksGauge.ScaleDirection = 'counterclockwise';
            app.ZangeRechtsGaugeLabel = uilabel(app.GripperPanel);
            app.ZangeRechtsGaugeLabel.Text = 'Zange Rechts';
            app.ZangeRechtsGauge = uigauge(app.GripperPanel, 'ninetydegree');
            app.ZangeRechtsGauge.MajorTicks = [0 30 60 90];
            app.KontaktLampLabel = uilabel(app.GripperPanel);
            app.KontaktLampLabel.Text = 'Kontakt';
            app.KontaktLamp = uilamp(app.GripperPanel);
            app.OffsetEditFieldLabel = uilabel(app.GripperPanel);
            app.OffsetEditFieldLabel.Text = 'Offset';
            app.OffsetEditField = uieditfield(app.GripperPanel, 'numeric');
            app.OffsetEditField_2 = uieditfield(app.GripperPanel, 'numeric');
            app.OffsetEditField_3 = uieditfield(app.GripperPanel, 'numeric');
            app.ffnenSchlieenButton = uibutton(app.GripperPanel, 'push');
            app.ffnenSchlieenButton.Text = 'Öffnen/Schließen';
            app.UIAxes = uiaxes(app.RoboterTab);
            title(app.UIAxes, 'Roboter Position')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UnterdruckpumpePanel = uipanel(app.RoboterTab);
            app.UnterdruckpumpePanel.Title = 'Unterdruckpumpe';
            app.SignalLampLabel = uilabel(app.UnterdruckpumpePanel);
            app.SignalLampLabel.Text = 'Signal';
            app.SignalLamp = uilamp(app.UnterdruckpumpePanel);
            app.DruckGaugeLabel = uilabel(app.UnterdruckpumpePanel);
            app.DruckGaugeLabel.Text = 'Druck';
            app.DruckGauge = uigauge(app.UnterdruckpumpePanel, 'linear');
            app.AnAusButton = uibutton(app.UnterdruckpumpePanel, 'push');
            app.AnAusButton.Text = 'An/Aus';
            app.MesssystemTab = uitab(app.TabGroup);
            app.MesssystemTab.Title = 'Messsystem';
            app.LoggingPanel = uipanel(app.UIFigure);
            app.LoggingPanel.Title = 'Logging';
            app.FehlerCheckBox = uicheckbox(app.LoggingPanel);
            app.FehlerCheckBox.Text = 'Fehler';
            app.FehlerCheckBox.Value = true;
            app.WarnungCheckBox = uicheckbox(app.LoggingPanel);
            app.WarnungCheckBox.Text = 'Warnung';
            app.WarnungCheckBox.Value = true;
            app.InfoCheckBox = uicheckbox(app.LoggingPanel);
            app.InfoCheckBox.Text = 'Info';
            app.InfoCheckBox.Value = true;
            app.DebugCheckBox = uicheckbox(app.LoggingPanel);
            app.DebugCheckBox.Text = 'Debug';
            app.DebugCheckBox.Value = true;
            app.UITable = uitable(app.LoggingPanel);
            app.UITable.ColumnName = {'Zeit'; 'Level'; 'Ursprung'; 'Nachricht'};
            app.UITable.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end